classdef Leaf_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LeafAnalyzerUIFigure           matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LeafAreaTab                    matlab.ui.container.Tab
        Step2SettingsPanel             matlab.ui.container.Panel
        SettingsTabGroup               matlab.ui.container.TabGroup
        OutputTab                      matlab.ui.container.Tab
        PerleafgreenindexCheckBox      matlab.ui.control.CheckBox
        MeangreenleafindexCheckBox     matlab.ui.control.CheckBox
        PerleafperimeterCheckBox       matlab.ui.control.CheckBox
        PerleafdimensionsCheckBox      matlab.ui.control.CheckBox
        PerleafareaCheckBox            matlab.ui.control.CheckBox
        AreaStdDevCheckBox             matlab.ui.control.CheckBox
        LabeltextCheckBox              matlab.ui.control.CheckBox
        UnitsDropDown                  matlab.ui.control.DropDown
        MeasurementunitsLabel          matlab.ui.control.Label
        MaxleafdimensionsCheckBox      matlab.ui.control.CheckBox
        MaxleafareaCheckBox            matlab.ui.control.CheckBox
        LeafcountCheckBox              matlab.ui.control.CheckBox
        MeanareaCheckBox               matlab.ui.control.CheckBox
        TotalareaCheckBox              matlab.ui.control.CheckBox
        MaskedRGBimageCheckBox         matlab.ui.control.CheckBox
        BinaryleafmaskCheckBox         matlab.ui.control.CheckBox
        OCRdetectionoverlayCheckBox    matlab.ui.control.CheckBox
        PatterndetectionoverlayCheckBox  matlab.ui.control.CheckBox
        OutputFormatDropDown           matlab.ui.control.DropDown
        OutputFormatDropDownLabel      matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        OutputpathEditField            matlab.ui.control.EditField
        OutputpathEditFieldLabel       matlab.ui.control.Label
        ClusteringTab                  matlab.ui.container.Tab
        PatternTypeButtonGroup_2       matlab.ui.container.ButtonGroup
        DGCICheckBox                   matlab.ui.control.CheckBox
        LBSCheckBox                    matlab.ui.control.CheckBox
        TextureDCheckBox               matlab.ui.control.CheckBox
        TextureMCheckBox               matlab.ui.control.CheckBox
        CCheckBox                      matlab.ui.control.CheckBox
        TextureCheckBox                matlab.ui.control.CheckBox
        LoGCheckBox                    matlab.ui.control.CheckBox
        HRFICheckBox                   matlab.ui.control.CheckBox
        ExGCheckBox                    matlab.ui.control.CheckBox
        FeaturesLabel                  matlab.ui.control.Label
        AttemptsEditField              matlab.ui.control.NumericEditField
        AttemptsEditFieldLabel         matlab.ui.control.Label
        ClustersEditField              matlab.ui.control.NumericEditField
        ClustersEditFieldLabel         matlab.ui.control.Label
        ThresholdingTab                matlab.ui.container.Tab
        LoadSettingsButton             matlab.ui.control.Button
        SaveSettingsButton             matlab.ui.control.Button
        SMinValue                      matlab.ui.control.NumericEditField
        ResetSettingsButton            matlab.ui.control.Button
        SelectRegionButton             matlab.ui.control.Button
        VMaxSlider                     matlab.ui.control.Slider
        VMaxSliderLabel                matlab.ui.control.Label
        VMinSlider                     matlab.ui.control.Slider
        VMinSliderLabel                matlab.ui.control.Label
        VMaxValue                      matlab.ui.control.NumericEditField
        VMinValue                      matlab.ui.control.NumericEditField
        SMaxSlider                     matlab.ui.control.Slider
        SMaxSliderLabel                matlab.ui.control.Label
        SMinSlider                     matlab.ui.control.Slider
        SMinSliderLabel                matlab.ui.control.Label
        SMaxValue                      matlab.ui.control.NumericEditField
        HMaxSlider                     matlab.ui.control.Slider
        HMaxSliderLabel                matlab.ui.control.Label
        HMinSlider                     matlab.ui.control.Slider
        HMinSliderLabel                matlab.ui.control.Label
        HMaxValue                      matlab.ui.control.NumericEditField
        HMinValue                      matlab.ui.control.NumericEditField
        PatternTab                     matlab.ui.container.Tab
        AprilTagLengthEditField        matlab.ui.control.NumericEditField
        AprilTagLengthEditFieldLabel   matlab.ui.control.Label
        mmLabel_3                      matlab.ui.control.Label
        PixelsLabel                    matlab.ui.control.Label
        BorderShrinkEditField          matlab.ui.control.NumericEditField
        BorderShrinkEditFieldLabel     matlab.ui.control.Label
        ProgressBar2                   wt.ProgressBar
        mmLabel_2                      matlab.ui.control.Label
        mmLabel                        matlab.ui.control.Label
        UsegridsLabel                  matlab.ui.control.Label
        CheckBoxUseGrids               matlab.ui.control.CheckBox
        PatternHeightEditField         matlab.ui.control.NumericEditField
        PatternHeightEditFieldLabel    matlab.ui.control.Label
        PatternWidthEditField          matlab.ui.control.NumericEditField
        PatternWidthEditFieldLabel     matlab.ui.control.Label
        AdvancedTab                    matlab.ui.container.Tab
        WatershedMinimaSpinner         matlab.ui.control.Spinner
        MinimaThresholdSpinnerLabel    matlab.ui.control.Label
        WatershedCheckBox              matlab.ui.control.CheckBox
        FillholesCheckBox              matlab.ui.control.CheckBox
        ResizeImageEditField           matlab.ui.control.NumericEditField
        ResizeImageEditFieldLabel      matlab.ui.control.Label
        ofOrignalImageLabel            matlab.ui.control.Label
        PerspectiveCorrectionCheckBox  matlab.ui.control.CheckBox
        MinLeafAreaEditField           matlab.ui.control.NumericEditField
        MinLeafAreaEditFieldLabel      matlab.ui.control.Label
        ofMaxLeafAreaLabel             matlab.ui.control.Label
        LeafAreaPreviewPanel           matlab.ui.container.Panel
        AxesSourcePanel                matlab.ui.container.Panel
        UIAxesSource                   matlab.ui.control.UIAxes
        AxesResultPanel                matlab.ui.container.Panel
        UIAxesResult                   matlab.ui.control.UIAxes
        Step3RunPanel                  matlab.ui.container.Panel
        CurrentImageFullName           matlab.ui.control.Label
        ProgressBar                    wt.ProgressBar
        RunButton                      matlab.ui.control.Button
        Step1SelectmethodpatternimagepathPanel  matlab.ui.container.Panel
        PatternTypeButtonGroup         matlab.ui.container.ButtonGroup
        PatternTypeC                   matlab.ui.control.RadioButton
        PatternLabel                   matlab.ui.control.Label
        PatternTypeB                   matlab.ui.control.RadioButton
        PatternTypeA                   matlab.ui.control.RadioButton
        SelectImageButton_2            matlab.ui.control.Button
        SegMethodButtonGroup           matlab.ui.container.ButtonGroup
        MethodLabel                    matlab.ui.control.Label
        ThresholdingButton             matlab.ui.control.RadioButton
        ClusteringButton               matlab.ui.control.RadioButton
        NumberOfImagesText             matlab.ui.control.Label
        NumberofimagesLabel            matlab.ui.control.Label
        PathText                       matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        LeafDamageTab                  matlab.ui.container.Tab
        LeafDamagePreviewPanel         matlab.ui.container.Panel
        UIAxesCluster3                 matlab.ui.control.UIAxes
        UIAxesCluster2                 matlab.ui.control.UIAxes
        UIAxesCluster1                 matlab.ui.control.UIAxes
        UIAxesDamagedLeaf              matlab.ui.control.UIAxes
        LeafDamageOptionsPanel         matlab.ui.container.Panel
        DrawROIButton                  matlab.ui.control.Button
        DrawMarginButton               matlab.ui.control.Button
        DamagedImageFullPath           matlab.ui.control.Label
        SelectImageButton              matlab.ui.control.Button
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        TypeGrazedButton               matlab.ui.control.RadioButton
        TypeLabel                      matlab.ui.control.Label
        TypeDiseasedButton             matlab.ui.control.RadioButton
        TypeHealthyButton              matlab.ui.control.RadioButton
        HyperspecTab                   matlab.ui.container.Tab
        PreviewPanel_3                 matlab.ui.container.Panel
        UIAxesHyperResult              matlab.ui.control.UIAxes
        OptionsPanel_2                 matlab.ui.container.Panel
        DetectPatternCheckBox          matlab.ui.control.CheckBox
        IndicesButtonGroup             matlab.ui.container.ButtonGroup
        MCARIIndexButton               matlab.ui.control.RadioButton
        EVIIndexButton                 matlab.ui.control.RadioButton
        ShowLabel                      matlab.ui.control.Label
        NDVIIndexButton                matlab.ui.control.RadioButton
        RGBIndexButton                 matlab.ui.control.RadioButton
        SelectImageButton_HSI          matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentRGB % current RGB image to be processed for Leaf area calculation
        currentFileName
        freeHandMask % mask of handdrawings on grazed leaf
        freeHandDrawings % handles of handdrawings on grazed leaf
        freeHandListener % event listener for freehand object
        imageList % list of images for leaf area calculation
        RGBGrazedLeaf
        BWGrazedLeaf %

%         RGBGrazedLeaf 
        yellowMaskGrazedLeaf
        hCube

        isPatternSizeSet
        lastDirectory
        
    end
    
    methods (Access = private)
        
        function [patternCorners, cropRegionVertices] = detectPatternCorners(app) % patternCorners, (Y,X)
            % detect the pattern and crop the image
            cropRegionVertices = []; % only used for Pattern A

            if app.PatternTypeA.Value
                [id,loc] = readAprilTag(app.currentRGB,"tag36h11");%"tag16h5", it will read the image from left to right and from up to bottom, so the 1st will be the topleft in the image                
                if ~(ismember(0, id) && ismember(1, id) && ismember(2, id) && ismember(3, id)) % all tags must be detected, id from 0 to 3
                    message = sprintf('%s\nPattern not detected!', app.currentFileName);
                    uialert(app.LeafAnalyzerUIFigure,message,'Error');
                    patternCorners = []; % if not assign any value, it will give error when calling this function
                    return;
                end
                
                shrinkPixels = 0; %app.BorderShrinkEditField.Value; % number of pixels to shrink the pattern region from the border %%% the following code only considers two orientations: upright and 90 degree clockwise rotation
                
                indices = find(id == 0); % top left ApriTag
                cornerTopLeft = [loc(4,2,indices(1)) loc(4,1,indices(1))]; % loc is 4X2X4, or (x,y,id), 4th corner of tag is topleft
                if loc(4,1,indices(1)) < loc(2,1,indices(1)) % in topleft tag, if the x of the topleft corner is smaller than the x of the bottom right corner, then the orientation is upright otherwise it's 90 degrees clockwise
                    cropRegionVertices = [loc(1,2,indices(1))+shrinkPixels loc(1,1,indices(1))+shrinkPixels; loc(2,2,indices(1))+shrinkPixels loc(2,1,indices(1))+shrinkPixels;loc(3,2,indices(1))+shrinkPixels loc(3,1,indices(1))+shrinkPixels]; % index 1(bottom left),2(bottom right),3(top right) of the top left tag; coordinates are in (y,x) format
                else
                    cropRegionVertices = [loc(1,2,indices(1))+shrinkPixels loc(1,1,indices(1))-shrinkPixels; loc(2,2,indices(1))+shrinkPixels loc(2,1,indices(1))-shrinkPixels;loc(3,2,indices(1))+shrinkPixels loc(3,1,indices(1))-shrinkPixels]; 
                end

                indices = find(id == 1); % top right ApriTag
                cornerTopRight = [loc(3,2,indices(1)) loc(3,1,indices(1))]; % 3rd corner of tag is topright
                if loc(4,1,indices(1)) < loc(2,1,indices(1)) % if the x of the topleft corner is smaller than the x of the bottom right corner, then the orientation is upright otherwise it's 90 degrees clockwise
                    cropRegionVertices = vertcat(cropRegionVertices, [loc(4,2,indices(1))+shrinkPixels loc(4,1,indices(1))-shrinkPixels; loc(1,2,indices(1))+shrinkPixels loc(1,1,indices(1))-shrinkPixels;loc(2,2,indices(1))+shrinkPixels loc(2,1,indices(1))-shrinkPixels]); % index 4(top left),1(bottom left),2(bottom right) of the top right tag
                else
                    cropRegionVertices = vertcat(cropRegionVertices, [loc(4,2,indices(1))-shrinkPixels loc(4,1,indices(1))-shrinkPixels; loc(1,2,indices(1))-shrinkPixels loc(1,1,indices(1))-shrinkPixels;loc(2,2,indices(1))-shrinkPixels loc(2,1,indices(1))-shrinkPixels]); % index 4(top left),1(bottom left),2(bottom right) of the top right tag
                end

                
                indices = find(id == 3); % bot right ApriTag
                cornerBotRight = [loc(2,2,indices(1)) loc(2,1,indices(1))]; % 2nd corner of tag is botright
                if loc(4,1,indices(1)) < loc(2,1,indices(1)) % if the x of the topleft corner is smaller than the x of the bottom right corner, then the orientation is upright otherwise it's 90 degrees clockwise
                    cropRegionVertices = vertcat(cropRegionVertices, [loc(3,2,indices(1))-shrinkPixels loc(3,1,indices(1))-shrinkPixels; loc(4,2,indices(1))-shrinkPixels loc(4,1,indices(1))-shrinkPixels;loc(1,2,indices(1))-shrinkPixels loc(1,1,indices(1))-shrinkPixels]); % index 3(top right),4(top left),1(bottom left) of the bot right tag
                else
                    cropRegionVertices = vertcat(cropRegionVertices, [loc(3,2,indices(1))-shrinkPixels loc(3,1,indices(1))+shrinkPixels; loc(4,2,indices(1))-shrinkPixels loc(4,1,indices(1))+shrinkPixels;loc(1,2,indices(1))-shrinkPixels loc(1,1,indices(1))+shrinkPixels]); % index 3(top right),4(top left),1(bottom left) of the bot right tag
                end
                
                indices = find(id == 2); % bot left ApriTag
                cornerBotLeft = [loc(1,2,indices(1)) loc(1,1,indices(1))]; % 1st corner of tag is botleft
                if loc(4,1,indices(1)) < loc(2,1,indices(1)) % if the x of the topleft corner is smaller than the x of the bottom right corner, then the orientation is upright otherwise it's 90 degrees clockwise
                    cropRegionVertices = vertcat(cropRegionVertices, [loc(2,2,indices(1))-shrinkPixels loc(2,1,indices(1))+shrinkPixels; loc(3,2,indices(1))-shrinkPixels loc(3,1,indices(1))+shrinkPixels;loc(4,2,indices(1))-shrinkPixels loc(4,1,indices(1))+shrinkPixels]); % index 2(bottom right), 3(top right),4(top left) of the bot left tag
                else
                    cropRegionVertices = vertcat(cropRegionVertices, [loc(2,2,indices(1))+shrinkPixels loc(2,1,indices(1))+shrinkPixels; loc(3,2,indices(1))+shrinkPixels loc(3,1,indices(1))+shrinkPixels;loc(4,2,indices(1))+shrinkPixels loc(4,1,indices(1))+shrinkPixels]); % index 2(bottom right), 3(top right),4(top left) of the bot left tag
                end
                
                
            else
                BW = im2bw(app.currentRGB,0.45);
                corners = detectFrame(BW); % frame corners (Y,X)  
                cornersSorted = sortrows(corners,2); % order by x coordinates, small to big, the first 2 will be the left 2 vertices
                    
                if cornersSorted(1,1) < cornersSorted(2,1) % compare y coordinates
                    cornerTopLeft = cornersSorted(1,:);
                    cornerBotLeft = cornersSorted(2,:);
                else        
                    cornerTopLeft = cornersSorted(2,:);
                    cornerBotLeft = cornersSorted(1,:);
                end
            
                if cornersSorted(3,1) < cornersSorted(4,1) % compare y coordinates
                    cornerTopRight = cornersSorted(3,:);
                    cornerBotRight = cornersSorted(4,:);
                else        
                    cornerTopRight = cornersSorted(4,:);
                    cornerBotRight = cornersSorted(3,:);
                end
                
                frameThickness = 20;
                cornerTopLeft = cornerTopLeft+[frameThickness frameThickness];
                cornerTopRight = cornerTopRight+[frameThickness -frameThickness];
                cornerBotRight = cornerBotRight+[-frameThickness -frameThickness];
                cornerBotLeft = cornerBotLeft+[-frameThickness frameThickness];
            end

            patternCorners = [cornerTopLeft;cornerTopRight; cornerBotRight; cornerBotLeft];

        end

        function values = readQRNumbers(app, I)
        %READQRNUMBERS Read three comma-separated numbers from a QR code.
        %
        %   values = readQRNumbers(imageFile)
        %
        %   If found, update the patter size and AprilTag length in settings.
        %                [] otherwise.
        %
        %   Examples of valid QR contents:
        %       "100,200,30"
        %       "100.5, 200, 30.2"
        %       "-10, 20.5, 3e-2"
        
            
            if size(I,3) == 3
                G = im2gray(I);
            else
                G = I;
            end
            BW = imbinarize(G, "adaptive",  "ForegroundPolarity", "dark"); %imadjust/imsharpen does not work well, tested
            msg = readBarcode(BW, "QR-CODE");
        
            % No QR code found
            if strlength(msg) == 0
                return;
            end
        
            % Require exactly three integers separated by commas
            pattern = '^\s*([1-9]\d*)\s*[,*×-]\s*([1-9]\d*)\s*[,*×-]\s*([1-9]\d*)(?=\s|$)';
        
            tokens = regexp(char(msg), pattern, 'tokens', 'once');
        
            % QR code does not contain exactly three integers
            if isempty(tokens)
                return;
            end
        
            % Convert strings to numbers, set pattern paramerters
             values = str2double(tokens);
             app.PatternWidthEditField.Value = values(1);
             app.PatternHeightEditField.Value = values(2);
             app.AprilTagLengthEditField.Value = values(3);
        end

        function res = checkPatternParameters(app)
            if ~(all(isfinite([app.PatternWidthEditField.Value, app.PatternHeightEditField.Value, app.AprilTagLengthEditField.Value])) && ...
                        all([app.PatternWidthEditField.Value, app.PatternHeightEditField.Value, app.AprilTagLengthEditField.Value] > 0)) % pattern size not/incorrectly filled
                    
                    prompt = {'Pattern width (mm):',  'Pattern height (mm):', 'AprilTag length (mm):'};
        
                    dlgtitle = 'Please input pattern parameters';
                    defaultValues = {'287', '340', '20'};                    
                    dims = [1 50; 1 50; 1 50];% [rows columns] for each input field
                    
                    while true
                        answer = inputdlg(prompt, dlgtitle, dims, defaultValues);
                    
                        % User clicked Cancel
                        if isempty(answer)
                            res = false;
                            return;
                        end
                    
                        patternWidth  = str2double(answer{1});
                        patternHeight = str2double(answer{2});
                        tagLength     = str2double(answer{3});
                    
                        if all(isfinite([patternWidth, patternHeight, tagLength])) && ...
                                all([patternWidth, patternHeight, tagLength] > 0)
                            app.PatternWidthEditField.Value = patternWidth;
                            app.PatternHeightEditField.Value = patternHeight;
                            app.AprilTagLengthEditField.Value = tagLength;
                            break;
                        end
                    
                        errordlg('Please enter three positive numeric values.', ...
                                 'Invalid Input');
                    
                        % Keep the user's previous input when reopening the dialog
                        defaultValues = answer;
                    end
            end
            res = true;
        end

        function [H, pixelArea, scaleX, scaleY, polygonMaskROI] = computeHomography(app, patternCorners, cropRegionVertices) % patternCorners, (Y,X)           

            % undistortion
            w = app.PatternWidthEditField.Value;
            h = app.PatternHeightEditField.Value;
            l = app.AprilTagLengthEditField.Value;

            % calculate the bounding box of the pattern region
            cornersSortedX = sortrows(patternCorners,2); % order by x coordinates, small to big, the first 2 will be the left 2 vertices
            cornersSortedY = sortrows(patternCorners,1); % order by y coordinates, small to big, the first 2 will be the top 2 vertices

            minPatternRegionX = (cornersSortedX(1,2) + cornersSortedX(2,2))/2; % the mean X of the left 2 vertices
            maxPatternRegionX = (cornersSortedX(3,2) + cornersSortedX(4,2))/2; % the mean X of the right 2 vertices
            minPatternRegionY = (cornersSortedY(1,1) + cornersSortedY(2,1))/2; % the mean Y of the top 2 vertices
            maxPatternRegionY = (cornersSortedY(3,1) + cornersSortedY(4,1))/2; % the mean Y of the bot 2 vertices

            % calculate the distance between the AprilTag1 and the 4 corners of the bounding box 
            boundingBox = [minPatternRegionY minPatternRegionX; minPatternRegionY maxPatternRegionX; maxPatternRegionY maxPatternRegionX; maxPatternRegionY minPatternRegionX];
            distanceSquared = (boundingBox(:,1) - patternCorners(1,1)).^2 + (boundingBox(:,2) - patternCorners(1,2)).^2; % distanceSquared is 4X1, 4 distances from Tag1 to four corners
            [minDis,minIndex] = min(distanceSquared); % find the index of the closest corner to Tag1 

            if minIndex == 1 % Tag1 close to top left corner of bbx
                if (maxPatternRegionX - minPatternRegionX)/w > (maxPatternRegionY - minPatternRegionY)/h % more space in X direction, the undistorted pattern will occupy whole Y of the bounding box
                    scaleY = h/(maxPatternRegionY - minPatternRegionY);
                    scaleX = scaleY;                
                else
                    scaleX = w/(maxPatternRegionX - minPatternRegionX);
                    scaleY = scaleX;
                end
                lp = l / scaleX; % AprilTag length in pixels, note that scaleX = scaleY
                wp = w / scaleX; % Pattern width in pixels
                hp = h / scaleX; % Pattern height in pixels;
                % worldPoints in [x,y] format, also added offset of Tag1
                worldPoints = [0 0; wp 0; wp hp; 0 hp; 0 lp; lp lp; lp 0; wp-lp 0; wp-lp lp; wp lp; wp hp-lp; wp-lp hp-lp; wp-lp hp; lp hp; lp hp-lp; 0 hp-lp]+ [minPatternRegionX minPatternRegionY];
            elseif minIndex == 2 
                if (maxPatternRegionX - minPatternRegionX)/h > (maxPatternRegionY - minPatternRegionY)/w % more space in X direction (or pattern height direction), the undistorted pattern will occupy whole Y of the bounding box
                    scaleY = w/(maxPatternRegionY - minPatternRegionY);
                    scaleX = scaleY;                
                else
                    scaleX = h/(maxPatternRegionX - minPatternRegionX);
                    scaleY = scaleX;
                end
                lp = l / scaleX; % AprilTag length in pixels, note that scaleX = scaleY
                wp = w / scaleX; % Pattern width in pixels
                hp = h / scaleX; % Pattern height in pixels;
                worldPoints = [0 0; 0 wp; -hp wp; -hp 0; -lp 0; -lp lp; 0 lp; 0 wp-lp; -lp wp-lp; -lp wp; -hp+lp wp; -hp+lp wp-lp; -hp wp-lp; -hp lp; -hp+lp lp; -hp+lp 0] + [maxPatternRegionX minPatternRegionY];% it's the inverse of minIndex == 4
            elseif minIndex == 3 
                if (maxPatternRegionX - minPatternRegionX)/w > (maxPatternRegionY - minPatternRegionY)/h % more space in X direction (or pattern height direction), the undistorted pattern will occupy whole Y of the bounding box
                    scaleY = h/(maxPatternRegionY - minPatternRegionY);
                    scaleX = scaleY;                
                else
                    scaleX = w/(maxPatternRegionX - minPatternRegionX);
                    scaleY = scaleX;
                end
                lp = l / scaleX; % AprilTag length in pixels, note that scaleX = scaleY
                wp = w / scaleX; % Pattern width in pixels
                hp = h / scaleX; % Pattern height in pixels;
                worldPoints = [0 0; -wp 0; -wp -hp; 0 -hp; 0 -lp; -lp -lp; -lp 0; -wp+lp 0; -wp+lp -lp; -wp -lp; -wp -hp+lp; -wp+lp -hp+lp; -wp+lp -hp; -lp -hp; -lp -hp+lp; 0 -hp+lp] + [maxPatternRegionX maxPatternRegionY];% it's the inverse of minIndex==1
            elseif minIndex == 4
                if (maxPatternRegionX - minPatternRegionX)/h > (maxPatternRegionY - minPatternRegionY)/w % more space in X direction (or pattern height direction), the undistorted pattern will occupy whole Y of the bounding box
                    scaleY = w/(maxPatternRegionY - minPatternRegionY);
                    scaleX = scaleY;                
                else
                    scaleX = h/(maxPatternRegionX - minPatternRegionX);
                    scaleY = scaleX;
                end
                lp = l / scaleX; % AprilTag length in pixels, note that scaleX = scaleY
                wp = w / scaleX; % Pattern width in pixels
                hp = h / scaleX; % Pattern height in pixels;
                worldPoints = [0 0; 0 -wp; hp -wp; hp 0; lp 0; lp -lp; 0 -lp; 0 -wp+lp; lp -wp+lp; lp -wp; hp-lp -wp; hp-lp -wp+lp; hp -wp+lp; hp -lp; hp-lp -lp; hp-lp 0] + [minPatternRegionX maxPatternRegionY];% it's the inverse of minIndex==2
            end           

            
            [rows, cols, ~] = size(app.currentRGB);
            if app.PatternTypeA.Value
                imagePointsCombined = [patternCorners;cropRegionVertices];
                imagePoints = [imagePointsCombined(:,2) imagePointsCombined(:,1)]; % switch the order of x and y, in patternCorners it is (y,x)
            else
                imagePoints = [patternCorners(:,2) patternCorners(:,1)]; % switch the order of x and y, in patternCorners it is (y,x)
                worldPoints = worldPoints(1:4,:);
            end
         
                        
            H = fitgeotrans(imagePoints, worldPoints, 'projective');    %(1:4,:)
            pixelArea = scaleX*scaleY; % area per pixel, mm^2


            if app.PatternTypeA.Value
                cropRegionVerticesHomogenous = [cropRegionVertices(:,2) cropRegionVertices(:,1) ones(12,1)];
                correctedRegionVerticesHomogenous = cropRegionVerticesHomogenous * H.T;
                correctedRegionVerticesHomogenous = correctedRegionVerticesHomogenous ./ correctedRegionVerticesHomogenous(:,3); % Normalize to get homogeneous coordinates
                correctedRegionVerticesHomogenous = correctedRegionVerticesHomogenous(:,1:2); % Extract first 2 columns
                polygonMaskROI = poly2mask(correctedRegionVerticesHomogenous(:,1),correctedRegionVerticesHomogenous(:,2),rows, cols);
            else
                cornerPointsHomogenous = [patternCorners(:,2) patternCorners(:,1) ones(4,1)];
                correctedCornerPointsHomogenous = cornerPointsHomogenous * H.T;
                correctedCornerPointsHomogenous = correctedCornerPointsHomogenous ./ correctedCornerPointsHomogenous(:,3); % Normalize to get homogeneous coordinates
                correctedCornerPointsHomogenous = correctedCornerPointsHomogenous(:,1:2); % Extract first 2 columns
                polygonMaskROI = poly2mask(correctedCornerPointsHomogenous(:,1),correctedCornerPointsHomogenous(:,2),rows, cols);
            end

        end

        function doSegmentation(app)            
            
            [patternCorners,cropRegionVertices] = detectPatternCorners(app);
            if isempty(patternCorners)
                return;
            end
            [rows, cols, ~] = size(app.currentRGB);

            if app.PatternTypeA.Value
                polygonMaskROI = poly2mask(cropRegionVertices(:,2),cropRegionVertices(:,1),rows, cols);
            else
                polygonMaskROI = poly2mask(patternCorners(:,2),patternCorners(:,1),rows, cols);
            end

            
            % QRRectImg = getQRRectImg(app.currentRGB,patternCorners);
            readQRNumbers(app, app.currentRGB); % check if there is QR code; if so, set pattern parameters           
            

            if app.PerspectiveCorrectionCheckBox.Value() 

                res = checkPatternParameters(app);
                if ~res
                    return;
                end

                [H,pixelArea, scaleX, scaleY, polygonMaskROI] = computeHomography(app, patternCorners, cropRegionVertices); %
                correctedCurrentRGB = imwarp(app.currentRGB, projective2d(H.T), 'OutputView', imref2d(size(app.currentRGB))); 
                roiRGB = correctedCurrentRGB;                
            else
                roiRGB = app.currentRGB;
            end

            if size(roiRGB,3) == 1 % if it's grayscale
                roiRGB = repmat(roiRGB, [1, 1, 3]); % repeat grayscale channels to 3
            end
            
            roiRGB(repmat(~polygonMaskROI,[1 1 3])) = 0; % set the masked-out region to black
            roiHSV = rgb2hsv(roiRGB);

            % shrink the ROI to avoid border effects in some cases    
            shrinkAmount = app.BorderShrinkEditField.Value; % Number of pixels to shrink
            shrinkedMask = imerode(polygonMaskROI, strel('disk', shrinkAmount));           

            if app.ClusteringButton.Value                             

%                 distanceImage = getAngularDistanceImage(app, app.currentRGB);

                S = single(roiHSV(:,:,2)); % change type to single, otherwise featureMatrix will be uint8

                imgLab = rgb2lab(roiRGB);

                
                grayImg = rgb2gray(roiRGB);                
                featureMatrix = [];
                L = single(imgLab(:,:,1));
                b = single(imgLab(:,:,3)); 
                featureMatrix = cat(3, L, b, S); 

                if app.DGCICheckBox.Value
                    H = roiHSV(:,:,1);   % degrees                    
                    V = roiHSV(:,:,3);         % 0–1                    
                    dgci = ((H - 60)/60 + (1 - S) + (1 - V)) / 3;
                    dgci = max(0, min(1, dgci));

                    featureMatrix = cat(3, featureMatrix, dgci);
                end

                if app.CCheckBox.Value
                    c = sqrt(single(imgLab(:,:,2)).^2 + single(imgLab(:,:,3)).^2);                    
                    featureMatrix = cat(3, featureMatrix, c);                    
                end

                if app.ExGCheckBox.Value                    
                    ExG = 2 * roiRGB(:,:,2) - roiRGB(:,:,1) - roiRGB(:,:,3);
                    ExG = single(ExG);
                    featureMatrix = cat(3, featureMatrix, ExG);
                end

                if app.HRFICheckBox.Value
                    sRGB = single(roiRGB);
                    HRFI = (sRGB(:,:,1) - sRGB(:,:,3)).*(sRGB(:,:,2) - sRGB(:,:,3)); 
                    featureMatrix = cat(3, featureMatrix, HRFI);
                end

                if app.TextureCheckBox.Value
                    grayImg = rgb2gray(roiRGB);
                    SE = strel("disk",4);
                    localEntropy = entropyfilt(grayImg, SE.Neighborhood); 
                    featureMatrix = cat(3, featureMatrix, localEntropy);
                end

                if app.TextureMCheckBox.Value
                    grayImg = rgb2gray(roiRGB);
                    grayImg = medfilt2(grayImg,[4,4]);

                    SE = strel("disk",4);
                    localEntropy = entropyfilt(grayImg, SE.Neighborhood); 
                    SE = strel("disk",8);
                    localRange = rangefilt(grayImg, SE.Neighborhood);
                    multi = localEntropy .* (1-rescale(localRange));                     
                    SE = strel("disk",10); openMulti = imopen(multi,SE);

                    featureMatrix = cat(3, featureMatrix, openMulti);
                end

                if app.TextureDCheckBox.Value
                    grayImg = rgb2gray(roiRGB);
                    SE = strel("disk",4);
                    localEntropy = entropyfilt(grayImg, SE.Neighborhood); 
                    SE = strel("disk",8);
                    localRange = rangefilt(grayImg, SE.Neighborhood);
                    diffRange = abs(rescale(localEntropy) - rescale(localRange)); 
                    SE = strel("disk",10); openDiffRange = imopen(diffRange,SE);             

                    featureMatrix = cat(3, featureMatrix, openDiffRange);
                end

                if app.LoGCheckBox.Value
                    imgGray = rgb2gray(roiRGB);
                    h = fspecial("log",3,0.4); 
                    LoG = single(imfilter(imgGray,h));                    
                    featureMatrix = cat(3, featureMatrix, LoG);
                end
                                                
                maskBW = kmeansCroppedMaskBW(app, featureMatrix, shrinkedMask);                

            elseif app.ThresholdingButton.Value
                roiRGB(repmat(~shrinkedMask,[1 1 3])) = 0; 
                [~,maskBW] = hsvSegmentation(roiRGB, app.HMinSlider.Value, app.HMaxSlider.Value, app.SMinSlider.Value,app.SMaxSlider.Value,app.VMinSlider.Value,app.VMaxSlider.Value);
            end
  
            
            %----- if fill holes -----%
            if app.FillholesCheckBox.Value
                maskBW = imfill(maskBW, 'holes');
            end

            CC = bwconncomp(maskBW); % connected components struct
            stats = regionprops(CC,"Area","BoundingBox"); % region property            
            maxArea = max([stats.Area]); % biggest leaf area

            idx = find([stats.Area] > app.MinLeafAreaEditField.Value/100.0*maxArea); % region with pixel number obove threshold. idx is region idx not pixel idx
            
            L = labelmatrix(CC);
            onlyLeafBW = ismember(L,idx);

            if app.WatershedCheckBox.Value
                D = -bwdist(~onlyLeafBW);  % negative so grains become "basins"

                % Suppress shallow minima to reduce over-segmentation
                D2 = imhmin(D, app.WatershedMinimaSpinner.Value);            % tweak the 1.5 'h' value to test
                
                % Run the watershed transform
                L = watershed(D2);
                
                % Zero-out watershed ridge lines so they become background
                onlyLeafBW(L == 0) = 0;
            end

            % computeRegionAngle(app, roiRGB);
            
            maskedRGB = roiRGB;    
            maskedRGB(repmat(~onlyLeafBW,[1 1 3])) = 0;
                
            imshow(maskedRGB,'Parent',app.UIAxesResult);
            app.UIAxesSource.Title.String = "Input";
            app.UIAxesResult.Title.String = "Output";
        end

        function drawAngleDistribution(app, rgbImage)
            lab = rgb2lab(im2double(rgbImage));  % size: H x W x 3
            roiHSV = double(rgb2hsv(rgbImage));
            h = roiHSV(:,:,1);
            s = roiHSV(:,:,2);
            v = roiHSV(:,:,3);
            L = lab(:,:,1); 
            a = lab(:,:,2);
            b = lab(:,:,3);
            lab = cat(3, L, b, s);
            
            mask = imread("/path/to/mask_segMATE.png");
            
            % Reshape Lab image and mask to lists
            [h, w, ~] = size(lab);
            labPixels = reshape(lab, [], 3);        % N×3
            maskFlat = reshape(mask, [], 1);        % N×1
            
            % Normalize each (L*,a*,b*) pixel to unit length
            norms = vecnorm(labPixels, 2, 2);       % Euclidean norm
            norms(norms == 0) = 1;                  % Prevent divide by zero
            labNorm = labPixels ./ norms;           % N×3, unit vectors
            
            % Assign colors based on mask
            colors = zeros(size(labNorm));          % N×3
            colors(maskFlat, :) = repmat([0 1 0], sum(maskFlat), 1);       % Green
            colors(~maskFlat, :) = repmat([1 0 0], sum(~maskFlat), 1);     % Red
            
            % 3D scatter plot
            figure('Color', 'w');
            scatter3(labNorm(:,1), labNorm(:,2), labNorm(:,3), ...
                     6, colors, 'filled');
            xlabel('H'); ylabel('S'); zlabel('V');
            title('HSV Feature Vector on Unit Sphere');
            axis equal;
            grid on;

        end

        function computeRegionAngle(app, rgbImage)
            % Convert RGB to Lab
            lab = rgb2lab(im2double(rgbImage));  % size: H x W x 3

            roiHSV = double(rgb2hsv(rgbImage));
            h = roiHSV(:,:,1);
            s = roiHSV(:,:,2);
            v = roiHSV(:,:,3);
            L = lab(:,:,1); 
            a = lab(:,:,2);
            b = lab(:,:,3);
            lab = cat(3, h, s, v);

            maskBW = imread("/path/to/mask_segMATE.png");
            
            % Get linear indices of each region
            idx1 = find(maskBW);
            idx2 = find(~maskBW);
            
            % Reshape Lab image to pixel list
            [h, w, ~] = size(lab);
            labFlat = reshape(lab, [], 3);
            
            % Extract pixel vectors for each region
            vecs1 = labFlat(idx1, :);  % [N1 x 3]
            vecs2 = labFlat(idx2, :);  % [N2 x 3]


            norms1 = vecnorm(vecs1, 2, 2);
            norms2 = vecnorm(vecs2, 2, 2);
            norms1(norms1 == 0) = 1;
            norms2(norms2 == 0) = 1;
                        
            % Normalize each vector
            unit1 = vecs1 ./ norms1;
            unit2 = vecs2 ./ norms2;
            
            % Compute average direction (then normalize it)
            meanVec1 = mean(unit1, 1);
            meanVec2 = mean(unit2, 1);

            meanVec1 = meanVec1 / norm(meanVec1);
            meanVec2 = meanVec2 / norm(meanVec2);

            cosTheta = dot(meanVec1, meanVec2);    % cosine of angle
            cosTheta = max(min(cosTheta, 1), -1);  % Clamp to valid range
            angleRad = acos(cosTheta);             % In radians
            angleDeg = rad2deg(angleRad);          % In degrees

            fprintf('Angles: %f | %f ', angleRad, angleDeg);
        end

        function show3DPoints(app, rgbImage)           

            % Read and prepare image            
            img = im2double(rgbImage);                % Ensure it's in [0,1] range
            
            % Convert to Lab color space
            labImg = rgb2lab(img);              % Size: [H, W, 3]
            roiHSV = double(rgb2hsv(rgbImage));
            h = rescale(roiHSV(:,:,1));
            s = rescale(roiHSV(:,:,2));
            v = rescale(roiHSV(:,:,3));
            L = rescale(labImg(:,:,1)); 
            a = rescale(labImg(:,:,2));
            b = rescale(labImg(:,:,3));
            labImg = cat(3, L, a, b);
            
            % Reshape image data into 2D arrays (pixels × channels)
            [h, w, ~] = size(labImg);
            labPixels = reshape(labImg, [], 3);     % [N, 3], where N = h * w
            rgbPixels = reshape(img, [], 3);        % [N, 3] for coloring
            
            % Normalize Lab vectors to unit length
            norms = vecnorm(labPixels, 2, 2);       % Compute norm of each Lab vector
            norms(norms == 0) = 1;                  % Avoid division by zero
            labNorm = labPixels ./ norms;           % Each row becomes unit vector
            
            % Plot in 3D using scatter3
            figure('Color', 'b');                   % Black background (optional)
            scatter3(labNorm(:,1), labNorm(:,2), labNorm(:,3), ...
                     6, rgbPixels, 'filled');
            
            xlabel('L*'); ylabel('a*'); zlabel('b*');
            title('Normalized CIELAB Vectors in 3D');
            axis equal;
            grid on;
            set(gca, 'Color', 'b');                 % Axes background black

        end

        function [maskBW] = mixLightDark(app, XDark, XLight)            
            maskBWDark = kmeansMaskBW(app, XDark);            
            maskBWLight = kmeansMaskBW(app, XLight);            

            maskBW = unionDarkLight(app, maskBWDark, maskBWLight);

        end

        % Mapping img to newImage, the top left corner of img is mapped to (pointX, pointY)  
        function [newImage] = imageMapToOriginalSize(app, img, newRows, newCols, pointX, pointY)
            newImage = zeros(newRows, newCols, 'single');
            [rowsImg, colsImg, ~] = size(img);
            newImage(pointY:pointY+colsImg-1, pointX:pointX+rowsImg-1, :) = img;
        end

        function [maskBW] = kmeansMaskBW(app, X)

            [L,centers] = imsegkmeans(single(X),3,'NumAttempts',app.AttemptsEditField.Value); % 3 clusters: background(NaN + white), foreground
                        
            % For visualization of classification
            figure;
            B = labeloverlay(app.currentRGB,L);
            imshow(B);
    
            props = regionprops(L, 'ConvexArea');
            convex_areas = [props.ConvexArea];
            sorted_convex_areas = sort(convex_areas, 'ascend');
            
            % Find the index of the label with the 2nd largest convex area
            leaf_label = find(convex_areas == sorted_convex_areas(1));  % label of leaf regions, leaf regions will have the smallest convex area
            maskBW = L== leaf_label;
        end

        function [distanceImage] = getAngularDistanceImage(app, rgbImg)
            img = double(rgbImg);

            % Vector for white color
            white = [255, 255, 255];
            
            % Compute the dot product for each pixel
            dot_product = img(:, :, 1) * white(1) + img(:, :, 2) * white(2) + img(:, :, 3) * white(3);
            
            % Compute the magnitude of each pixel vector
            magnitude_img = sqrt(img(:, :, 1).^2 + img(:, :, 2).^2 + img(:, :, 3).^2);
            
            % Compute the magnitude of the white vector
            magnitude_white = sqrt(sum(white.^2));            

            cos_theta = dot_product ./ (magnitude_img * magnitude_white);
            
            % Define angular distance as 1 - cos(θ)
            angular_distance = 1 - cos_theta;
            
            % Save the result as a 2D array
            distanceImage = angular_distance;

            filterMask = magnitude_img<10;
            distanceImage(filterMask) = 0;
        end

        function [maskBW] = kmeansCroppedMaskBW(app, img, polygonMask)            
            
            % Step 1: Extract RGB pixels from the masked region
            [rows, cols, chs] = size(img);            
            imgPixelList = reshape(img, [], size(img,3)); % flatten img
            maskFlat = polygonMask(:); % Flatten the mask to a column vector
            maskedPixelList = imgPixelList(maskFlat, :); % Extract feature values of the masked region

            % normalize each column (feature) in maskedPixelList
            maskedPixelList_min = min(maskedPixelList); % Minimum value of each column
            maskedPixelList_max = max(maskedPixelList); % Maximum value of each column
            range = maskedPixelList_max - maskedPixelList_min;
            range(range == 0) = 1; % To avoid division by 0, if min=max, all values the same, in this case, the result will be 0 (the minimum of range [0,1])            
            maskedPixelList = (maskedPixelList - maskedPixelList_min) ./ range; % Normalize
            maskedPixelList(:,1) = maskedPixelList(:,1) + 1e-6; % First feature all added by 1e-6 to ensure cosine distance works
          
            % Step 2: Perform k-means clustering on the masked region
            numClusters = 2; % Number of clusters
            [idx, centers, sumd] = kmeans(maskedPixelList, numClusters, 'Replicates',app.AttemptsEditField.Value,'Distance','cosine'); %statset('Streams',RandStream('mlfg6331_64'), 'UseSubstreams',true)
            
            % Step 3: Map the clustering results back to the mask
            segmentedImage = zeros(rows, cols); % Initialize a blank image
            segmentedImage(polygonMask) = idx; % Assign cluster labels to the masked region, segmentedImage(polygonMask) is N x 1, but segmentedImage is rows x cols


            props = regionprops(segmentedImage, 'ConvexArea');
            convex_areas = [props.ConvexArea];
            sorted_convex_areas = sort(convex_areas, 'ascend');
            
            % Find the index of the label with the 2nd largest convex area
            leaf_label = find(convex_areas == sorted_convex_areas(1));  % label of leaf regions, leaf regions will have the smallest convex area
            maskBW = segmentedImage == leaf_label;

        end

        function [C] = unionDarkLight(app, A, B)
            [labeledA, numRegionsA] = bwlabel(A);
            [labeledB, numRegionsB] = bwlabel(B);
            
            % Initialize output array C
            C = false(size(A));
            
            % Step 2: Process regions in A
            for i = 1:numRegionsA
                % Get the mask for region RA in A
                regionA = (labeledA == i);
                areaA = sum(regionA(:)); % Area of region RA
            
                % Compute the overlap with B
                overlapB = regionA & B;
                areaOverlapB = sum(overlapB(:)); % Area of overlap with B
            
                % If overlap is smaller than 30% of RA, include RA in C
                if (areaOverlapB / areaA) < 0.3
                    C = C | regionA;
                end
            end
            
            % Step 3: Process regions in B
            for j = 1:numRegionsB
                % Get the mask for region RB in B
                regionB = (labeledB == j);
                areaB = sum(regionB(:)); % Area of region RB
            
                % Compute the overlap with A
                overlapA = regionB & A;
                areaOverlapA = sum(overlapA(:)); % Area of overlap with A
            
                % If overlap is greater than 70% of RB, include RB in C
                if (areaOverlapA / areaB) > 0.7
                    C = C | regionB;
                end
            end

        end        

        function updateAxisImage(app, img, axis)            
            cla(axis);
            imshow(img,'Parent',axis);
        end

        function computeHerbRate(app, src, evt)
            if ~isvalid(src) % the handle could points to a deleted freehand object. in this case, it can still evoke callback
                return;
            end

            % callback can be triggered from other events, such as other buttons clicked or new tab clicked, so it's necessary to chek
            % if it's really from "DrawingFinished" event
            szPoints = size(src.Position);
            if(szPoints(1)<2)
                return;
            end

            app.DrawMarginButton.Text = "Remove Margin"; % whenever a new drawing finished
            
            
            imageChildren = findobj(app.UIAxesCluster1.Children, 'Type', 'Image'); % get the image child
            freeHandMask = createMask(src, imageChildren(1)); % must specify the image to allow multiple callbacks, otherwise get ambiguity error
            se = strel('disk',4);
        
            freeHandMask = imdilate(freeHandMask,se); % the mask will be disconnected somewhere, so close it
            app.freeHandMask = app.freeHandMask | freeHandMask;
            BWRecoveredLeaf = app.freeHandMask | app.BWGrazedLeaf;
            BWFilledRecoveredLeaf = imfill(BWRecoveredLeaf, 'holes');
            
            herbivoryRate = 1 - double(nnz(app.BWGrazedLeaf))/nnz(BWFilledRecoveredLeaf);    
            BWHoles = BWFilledRecoveredLeaf - app.BWGrazedLeaf;
            
            yellowMask = cat(3, BWHoles, BWHoles, zeros(size(BWHoles)));
            hold(app.UIAxesCluster1, 'on' );
            delete(app.yellowMaskGrazedLeaf); % delete previous yellow masks, otherwise masks will overlay and color becomes brighter
            app.yellowMaskGrazedLeaf = imshow(yellowMask,'Parent',app.UIAxesCluster1);
            set(app.yellowMaskGrazedLeaf, 'AlphaData', 0.5 * BWHoles);
            app.UIAxesCluster1.Title.String = sprintf("Herbivory rate: %.1f%%",herbivoryRate*100);                    
            hold(app.UIAxesCluster1, 'off' );

            h = images.roi.Freehand(app.UIAxesCluster1, 'Closed',false, 'LineWidth',1); % to allow next drawing
            app.freeHandDrawings = [app.freeHandDrawings,h]; % append new drawing,if nothing actually drawn, h is empty freehand object
            delete(app.freeHandListener); % delete existing listener from past events
            app.freeHandListener = addlistener(h,'DrawingFinished',@(src,evt)app.computeHerbRate(src,evt));            
            draw(h);
            
        
        end

        function recalculatePercentDamage(app)
          BWFilledGrazedLeaf = imfill(app.BWGrazedLeaf, 'holes');            
            herbivoryRate = 1 - double(nnz(app.BWGrazedLeaf))/nnz(BWFilledGrazedLeaf);    
            BWHoles = BWFilledGrazedLeaf - app.BWGrazedLeaf;

            maskedRGB = app.RGBGrazedLeaf;    
            maskedRGB(repmat(~app.BWGrazedLeaf,[1 1 3])) = 0;
            imshow(maskedRGB,'Parent',app.UIAxesCluster1);
            yellowMask = cat(3, BWHoles, BWHoles, zeros(size(BWHoles)));
            hold(app.UIAxesCluster1, 'on' );
            app.yellowMaskGrazedLeaf = imshow(yellowMask,'Parent',app.UIAxesCluster1);
            set(app.yellowMaskGrazedLeaf, 'AlphaData', 0.5 * BWHoles);
            app.UIAxesCluster1.Title.String = sprintf("Herbivory rate: %.1f%%",herbivoryRate*100);
                    
            hold(app.UIAxesCluster1, 'off' );
        end

        function roiMovedGrazedLeafCallback(app, src, evt)
            if ~isvalid(src) % the handle could points to a deleted freehand object. in this case, it can still evoke callback
                return;
            end

            BW = ~im2bw(app.RGBGrazedLeaf,0.45);
            rect = round(src.Position); 
            rectEndY = rect(2)+rect(4);
            if rectEndY > size(BW,1) % to avoid drawing out of boundary
                rectEndY = size(BW,1);
            end
            rectEndX = rect(1)+rect(3);
            if rectEndX > size(BW,2)
                rectEndX = size(BW,2);
            end

            
            mask = zeros(size(BW)); % Create binary mask
            mask(rect(2):rectEndY, rect(1):rectEndX) = 1; 
            app.BWGrazedLeaf = BW .* mask; % Apply mask to grayscale image

            recalculatePercentDamage(app);
            app.DrawROIButton.Enable = "off"; % only allow one drawing, disable drawing after finish
            app.freeHandMask = zeros(size(app.BWGrazedLeaf)); 
        end

        
        function roiDeletedGrazedLeafCallback(app, src, evt)
            if ~isvalid(src) % the handle could points to a deleted freehand object. in this case, it can still evoke callback
                return;
            end
            
            X = rgb2lab(app.RGBGrazedLeaf);
            L = imsegkmeans(single(X),2,'NumAttempts',2); % 3 clusters: background, healthy, unhealthy
            [rows, cols] = size(L);

            total = L(1,1) + L(1,cols) + L(rows,cols) + L(rows,1); % sum of labels of the four corners
            if total <= 5 % allow one error, this means bg has label "1"
                app.BWGrazedLeaf = L==2;
            else
                app.BWGrazedLeaf = L==1;
            end
            app.freeHandMask = zeros(size(app.BWGrazedLeaf));

            recalculatePercentDamage(app);
            app.DrawROIButton.Enable = "on"; % reactivate drawroi button
        end

        function hyperImageButtonDownCallback(app, src, evt)
            if isempty(app.hCube)
                return;
            end
            pointClicked = round(evt.IntersectionPoint(1:2)); %(x,y)
            if evt.Button == 3 % right click,1 is left click
                spectrum = squeeze(app.hCube.DataCube(pointClicked(2), pointClicked(1), :));  % Extract the spectrum at (x, y)
                figure('Name','Pixel spectra','NumberTitle','off');
                plot(spectrum);
                title(sprintf('Spectrum at Point (%d, %d)', pointClicked(1),pointClicked(2)));
                xlabel('Band');
                ylabel('Intensity');
            end
        end
     

        function UIAxesDamagedLeafMotionCallback(app, event, startCoord)
            currentCoord = app.UIAxesDamagedLeaf.CurrentPoint(1, 1:2); % Get current coordinates
            width = currentCoord(1) - startCoord(1); % Compute width of the rectangle
            height = currentCoord(2) - startCoord(2); % Compute height of the rectangle
            rectangle(app.UIAxesDamagedLeaf,'Position', [startCoord, width, height], 'EdgeColor', 'red', 'LineWidth', 2); % Update rectangle
        end
        
        function UIAxesDamagedLeafButtonUpCallback(app, event, startCoord)
            currentCoord = app.UIAxesDamagedLeaf.CurrentPoint(1, 1:2); % Get current coordinates
            width = currentCoord(1) - startCoord(1); % Compute width of the rectangle
            height = currentCoord(2) - startCoord(2); % Compute height of the rectangle
            rectangle(app.UIAxesDamagedLeaf,'Position', [startCoord, width, height], 'EdgeColor', 'red', 'LineWidth', 2); % Finalize rectangle
            set(app.UIAxesDamagedLeaf, 'WindowButtonMotionFcn', ''); % Reset motion callback
            set(app.UIAxesDamagedLeaf, 'WindowButtonUpFcn', ''); % Reset button release callback
            
            % Create mask based on the rectangle coordinates
            x = [startCoord(1), currentCoord(1), currentCoord(1), startCoord(1), startCoord(1)];
            y = [startCoord(2), startCoord(2), currentCoord(2), currentCoord(2), startCoord(2)];
            mask = poly2mask(x, y, size(app.BWGrazedLeaf, 1), size(app.BWGrazedLeaf, 2));
            app.BWGrazedLeaf = app.BWGrazedLeaf .* mask;
            
        end

        function initializeAxis(app,axis)
            axis.Units = "normalized";
            axis.InnerPosition = axis.Position;
            text(axis, 0.5, 0.5, 'APPN-Tech',  'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','Fontsize',30, 'Color','#eaecee', 'FontAngle', 'italic');
        end
        function preventMainAppWindowMinimized(app)
            % workaround to prevent main app window from being minimized
            drawnow;
            figure(app.LeafAnalyzerUIFigure);
        end

        function setResizeRatio(app, orgImg) % Set recommended resize ratio
            maxDim = max(size(orgImg));
            if maxDim > 7000
                app.ResizeImageEditField.Value = 30;
                app.BorderShrinkEditField.Value = 8;
            elseif maxDim > 5000
                app.ResizeImageEditField.Value = 40;
                app.BorderShrinkEditField.Value = 5;
            elseif maxDim > 3000
                app.ResizeImageEditField.Value = 50;
                app.BorderShrinkEditField.Value = 4;
            elseif maxDim > 2000
                app.ResizeImageEditField.Value = 60;
                app.BorderShrinkEditField.Value = 3;
            else
                app.ResizeImageEditField.Value = 100;
                app.BorderShrinkEditField.Value = 2;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            initializeAxis(app,app.UIAxesSource);
            initializeAxis(app,app.UIAxesResult);
            initializeAxis(app,app.UIAxesDamagedLeaf);
            initializeAxis(app,app.UIAxesCluster1);
            initializeAxis(app,app.UIAxesCluster2);
            initializeAxis(app,app.UIAxesCluster3);            
            initializeAxis(app,app.UIAxesHyperResult);

            app.UIAxesDamagedLeaf.Toolbar.Visible = 'off';
            app.UIAxesCluster1.Toolbar.Visible = 'off';  
            
            disableDefaultInteractivity(app.UIAxesDamagedLeaf);
%             disableDefaultInteractivity(app.UIAxesCluster1);
%             app.UIAxesDamagedLeaf.PickableParts = 'none';

            % note: AxesSourcePanel and AxesResultPanel must be kept, otherwise it will blink during run
            app.AxesSourcePanel.Units = "normalized";
            app.AxesResultPanel.Units = "normalized";
            app.AxesSourcePanel.Position = [0 0 0.5 1]; 
            app.AxesResultPanel.Position = [0.5 0 0.5 1];
            app.UIAxesSource.Position = [0.1 0.1 0.85 0.8]; 
            app.UIAxesResult.Position = [0.05 0.1 0.85 0.8];
 
            app.UIAxesDamagedLeaf.Position = [0.1 0.55 0.35 0.35];
            app.UIAxesCluster1.Position = [0.55 0.55 0.35 0.35]; 
            app.UIAxesCluster2.Position = [0.1 0.1 0.35 0.35]; 
            app.UIAxesCluster3.Position = [0.55 0.1 0.35 0.35]; 

            app.UIAxesHyperResult.Position = [0.1 0.1 0.8 0.8]; 

            app.DrawMarginButton.Visible = "off";
            app.DrawROIButton.Visible = "off";

            movegui(app.LeafAnalyzerUIFigure, 'center');

            app.Step2SettingsPanel.Enable = "off";
            app.Step3RunPanel.Enable = "off";
            tabComponents = app.SettingsTabGroup.Children(3).Children;                
            set(tabComponents, 'Enable', 'off'); % Disable the setting in 'Thresholding tab' components 

            app.CheckBoxUseGrids.Enable = "off";

            app.isPatternSizeSet = false;
            
            
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg; *.JPG; *.jpeg; *.JPEG; *.png; *.PNG; *.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF'}, 'Select an Image File',app.lastDirectory);
            preventMainAppWindowMinimized(app);
            
            if file 
                fileFullName = fullfile(path,file);
                app.DamagedImageFullPath.Text = fileFullName;
                app.lastDirectory = path;
            else % in case user open the selection dialogue but cancel it
                return;
            end

            RGB = imreadort(fileFullName);
            setResizeRatio(app, RGB);
            RGB = imresize(RGB, app.ResizeImageEditField.Value/100.0);

            if app.TypeDiseasedButton.Value
                
                imshow(RGB,'Parent',app.UIAxesDamagedLeaf);
                app.UIAxesDamagedLeaf.Title.String = "Input";
    
                X = rgb2lab(RGB); %rgb2lab(RGB);
                    
                % Auto clustering
                s = rng;
                rng('default');

                L = imsegkmeans(single(X),3,'NumAttempts',2); % 3 clusters: background, healthy, unhealthy
                rng(s);
                
                cluster = {};
                vectorBBoxArea = [];            
                for i=1:3
                    cluster{i} = L == i;
                    stats = regionprops(cluster{i},"BoundingBox");
                    
                    % get the region with the biggest bbox in this cluster
                    maxBBoxArea = 0;
                    maxBBoxRegionIdx = 0;
                    for j = 1:numel(stats)
                        bb = stats(j).BoundingBox;
                        BBoxArea = bb(3)*bb(4);
                        if BBoxArea > maxBBoxArea
                            maxBBoxArea = BBoxArea;
                            maxBBoxRegionIdx = j;
                        end
                    end
    
                    vectorBBoxArea(i) = maxBBoxArea;
                end  
    
                [v_max, idMax] = max(vectorBBoxArea); % find the cluster with max bounding box area, which will be the background
                maskedRGB = RGB;    
                maskedRGB(repmat(~cluster{idMax},[1 1 3])) = 0;
                imshow(maskedRGB,'Parent',app.UIAxesCluster1); % display background on the last axes
                app.UIAxesCluster1.Title.String = "Background";
    
                cluster = cluster([1:idMax-1, idMax+1:end]); % remove the bg cluster from the vector
                stats = regionprops(cluster{1},"Area");
                areaCluster1 = sum([stats.Area]);
                stats = regionprops(cluster{2},"Area");
                areaCluster2 = sum([stats.Area]);
                leafArea = areaCluster1 + areaCluster2; % total area: healthy + unhealthy
                maskedRGB = RGB;    
                maskedRGB(repmat(~cluster{1},[1 1 3])) = 0;
                imshow(maskedRGB,'Parent',app.UIAxesCluster2);
                app.UIAxesCluster2.Title.String = sprintf("%.1f%%",areaCluster1*100/leafArea);
                maskedRGB = RGB;    
                maskedRGB(repmat(~cluster{2},[1 1 3])) = 0;
                imshow(maskedRGB,'Parent',app.UIAxesCluster3);
                app.UIAxesCluster3.Title.String = sprintf("%.1f%%",areaCluster2*100/leafArea);

            elseif app.TypeGrazedButton.Value
                app.RGBGrazedLeaf = RGB;
                hDamagedImg = imshow(RGB,'Parent',app.UIAxesDamagedLeaf);
                
                app.UIAxesDamagedLeaf.Title.String = "Input";

                X = rgb2lab(RGB);
                L = imsegkmeans(single(X),2,'NumAttempts',2); % 3 clusters: background, healthy, unhealthy
                [rows, cols] = size(L);

                total = L(1,1) + L(1,cols) + L(rows,cols) + L(rows,1); % sum of labels of the four corners
                if total <= 5 % allow one error, this means bg has label "1"
                    app.BWGrazedLeaf = L==2;
                else
                    app.BWGrazedLeaf = L==1;
                end
                app.freeHandMask = zeros(size(app.BWGrazedLeaf));

                BWFilledGrazedLeaf = imfill(app.BWGrazedLeaf, 'holes');            
                herbivoryRate = 1 - double(nnz(app.BWGrazedLeaf))/nnz(BWFilledGrazedLeaf);    
                BWHoles = BWFilledGrazedLeaf - app.BWGrazedLeaf;

                maskedRGB = RGB;    
                maskedRGB(repmat(~app.BWGrazedLeaf,[1 1 3])) = 0;
                imshow(maskedRGB,'Parent',app.UIAxesCluster1);
                yellowMask = cat(3, BWHoles, BWHoles, zeros(size(BWHoles)));
                hold(app.UIAxesCluster1, 'on' );
                app.yellowMaskGrazedLeaf = imshow(yellowMask,'Parent',app.UIAxesCluster1);
                set(app.yellowMaskGrazedLeaf, 'AlphaData', 0.5 * BWHoles);
                app.UIAxesCluster1.Title.String = sprintf("Percent herbivory: %.1f%%",herbivoryRate*100);
                        
                hold(app.UIAxesCluster1, 'off' );
                app.DrawMarginButton.Text = "Draw Margin";
                app.DrawMarginButton.Visible = "on";
                app.DrawROIButton.Visible = "on";
                app.DrawROIButton.Enable = "on"; % the drawroi button could be disabled previously
                                
                                
            elseif app.TypeHealthyButton.Value

                X = rgb2lab(RGB);
                L = imsegkmeans(single(X),2,'NumAttempts',2); % 2 clusters: background, healthy, unhealthy
                [rows, cols] = size(L);
                total = L(1,1) + L(1,cols) + L(rows,cols) + L(rows,1); % sum of labels of the four corners
                if total <= 5 % allow one error, this means bg has label "1"
                    BWGrazedLeaf = L==2;
                else
                    BWGrazedLeaf = L==1;
                end
                
                imshow(RGB,'Parent',app.UIAxesDamagedLeaf);
                app.UIAxesDamagedLeaf.Title.String = "Input";
                maskedRGB = RGB;    
                maskedRGB(repmat(~BWGrazedLeaf,[1 1 3])) = 0;
                imshow(maskedRGB,'Parent',app.UIAxesCluster1); 
                app.UIAxesCluster1.Title.String = "Leaves";
            end

            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            selpath = uigetdir(app.lastDirectory);
            preventMainAppWindowMinimized(app);
            
            if selpath
                app.PathText.Text = selpath;
                app.lastDirectory = selpath;
            else % in case user open the selection dialogue but cancel it
                return;
            end
            % a new task, clear previous progress
            app.CurrentImageFullName.Text = "Path: "+selpath;
            app.ProgressBar.startProgress("Press 'Run' button to start...");            
            try
                imds = imageDatastore(selpath, 'IncludeSubfolders', false, 'FileExtensions', {'.jpg','.jpeg','.png','.img','.tif','.tiff'});
                % Ignore hidden/macOS metadata files
                files = string(imds.Files);
                [~, names, ~] = fileparts(files);
                files(startsWith(names, ".")) = [];
                app.imageList = files;
            catch
                app.imageList = [];
            end
            numOfImagesTotal = numel(app.imageList);
            app.NumberOfImagesText.Text = string(numOfImagesTotal);
            if numOfImagesTotal > 0
                app.currentRGB = imreadort(string(app.imageList(1)));
                % readQRNumbers(app, app.currentRGB); % check if there is QR code; if so, set pattern parameters
                setResizeRatio(app, app.currentRGB);
                app.currentRGB = imresize(app.currentRGB, app.ResizeImageEditField.Value/100.0);
                app.currentFileName = app.imageList(1);                
                updateAxisImage(app, app.currentRGB, app.UIAxesSource);
            
                app.Step2SettingsPanel.Enable = "on";
                app.Step3RunPanel.Enable = "on";
                mkdir(selpath+"/output"); % create a folder for outputs
                app.OutputpathEditField.Value = selpath+"/output/";
                if app.ClusteringButton.Value
                    doSegmentation(app);
                end
                if app.ThresholdingButton.Value
                    doSegmentation(app);
                end
                
%                 updateAxisImage(app,app.currentRGB, app.UIAxesSource);
            end
        end

        % Value changing function: HMinSlider
        function HMinSliderValueChanging(app, event)
            app.HMinValue.Value =  event.Value;            
        end

        % Value changed function: HMinSlider
        function HMinSliderValueChanged(app, event)
            doSegmentation(app);
        end

        % Value changing function: HMaxSlider
        function HMaxSliderValueChanging(app, event)
            app.HMaxValue.Value =  event.Value; 
        end

        % Value changed function: HMaxSlider
        function HMaxSliderValueChanged(app, event)
            doSegmentation(app);
        end

        % Value changing function: SMinSlider
        function SMinSliderValueChanging(app, event)
            app.SMinValue.Value =  event.Value;             
        end

        % Value changed function: SMinSlider
        function SMinSliderValueChanged(app, event)
            if event.Value > app.SMaxSlider.Value
                app.SMinSlider.Value = app.SMaxSlider.Value;
                app.SMinValue.Value =  app.SMaxSlider.Value;
            end

            doSegmentation(app);            
        end

        % Value changing function: SMaxSlider
        function SMaxSliderValueChanging(app, event)
            app.SMaxValue.Value =  event.Value; 
        end

        % Value changed function: SMaxSlider
        function SMaxSliderValueChanged(app, event)
            if event.Value < app.SMinSlider.Value
                app.SMaxSlider.Value = app.SMinSlider.Value;
                app.SMaxValue.Value =  app.SMinSlider.Value;
            end

            doSegmentation(app);            
        end

        % Value changing function: VMinSlider
        function VMinSliderValueChanging(app, event)
            app.VMinValue.Value =  event.Value; 
        end

        % Value changed function: VMinSlider
        function VMinSliderValueChanged(app, event)
            if event.Value > app.VMaxSlider.Value
                app.VMinSlider.Value = app.VMaxSlider.Value;
                app.VMinValue.Value =  app.VMaxSlider.Value;
            end

            doSegmentation(app);
        end

        % Value changing function: VMaxSlider
        function VMaxSliderValueChanging(app, event)
            app.VMaxValue.Value =  event.Value; 
        end

        % Value changed function: VMaxSlider
        function VMaxSliderValueChanged(app, event)
            if event.Value < app.VMinSlider.Value
                app.VMaxSlider.Value = app.VMinSlider.Value;
                app.VMaxValue.Value =  app.VMinSlider.Value;
            end

            doSegmentation(app);
        end

        % Button pushed function: SelectRegionButton
        function SelectRegionButtonPushed(app, event)
            roi = roipoly(app.currentRGB);
            close(gcf);
            hsvImg = rgb2hsv(app.currentRGB);
            HMatrix = hsvImg(:,:,1);
            SMatrix = hsvImg(:,:,2);
            VMatrix = hsvImg(:,:,3);

            figure('Name','HSV channel histograms','NumberTitle','off');
            subplot(2, 1, 1);
            histogram(HMatrix(roi));
            title('H histogram');
            
            % Plot the second image
            subplot(2, 2, 3);
            histogram(SMatrix(roi));
            title('S histogram');
            
            % Plot the third image
            subplot(2, 2, 4);
            histogram(VMatrix(roi));
            title('V histogram');
        end

        % Button pushed function: ResetSettingsButton
        function ResetSettingsButtonPushed(app, event)
            app.HMinValue.Value = 0.217;
            app.HMinSlider.Value = 0.217;
            app.HMaxValue.Value = 0.421;            
            app.HMaxSlider.Value = 0.421;
            app.SMinValue.Value = 0.329;
            app.SMinSlider.Value = 0.329;
            app.SMaxValue.Value = 0.826;            
            app.SMaxSlider.Value = 0.826;
            app.VMinValue.Value = 0.065;            
            app.VMinSlider.Value = 0.065;
            app.VMaxValue.Value = 0.663;
            app.VMaxSlider.Value = 0.663;
            doSegmentation(app); 
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
           
            % check if pattern parameters are properly set
            res = checkPatternParameters(app);
            if ~res
                return;
            end

            app.RunButton.Enable = "off"; % prevent multiple runs at the same time
            
%             app.SegMethodButtonGroup.Enable = "off"; % cannot change seg method after Run button clicked
            app.Step1SelectmethodpatternimagepathPanel.Enable = "off"; % cannot change after Run button clicked
            app.Step2SettingsPanel.Enable = "off"; % cannot change settings during running            

            app.ProgressBar.startProgress("Starting...");
            pause(0.2);

            tic;
            T = table;
            % loop images in the path
            for id = 1:numel(app.imageList)

                
                [~,imgName,ext] = fileparts(app.imageList(id));
                fprintf('id =%d img =%s \n',id, imgName);
                T.ImageID = {imgName};
            
                app.currentFileName = imgName+ext;
                app.currentRGB = imreadort(app.imageList(id));   %app.currentRGB = imreadort(app.PathText.Text + "/" + app.imageList(id));              
                %readQRNumbers(app, app.currentRGB); % check if there is QR code; if so, set pattern parameters
                if id > 1 % skip the first image, because the first image has been scanned for QR in preview
                    % QRRectImg = getQRRectImg(app.currentRGB,patternCorners);
                    readQRNumbers(app, app.currentRGB); % check if there is QR code; if so, set pattern parameters
                end
                T.PatternWidth = app.PatternWidthEditField.Value;
                T.PatternHeight = app.PatternHeightEditField.Value;
                T.AprilTagLength = app.AprilTagLengthEditField.Value;
                app.currentRGB = imresize(app.currentRGB, app.ResizeImageEditField.Value/100.0);
                % BW = im2bw(app.currentRGB,0.45);
                                
                updateAxisImage(app, app.currentRGB, app.UIAxesSource);

                % update progress bar
                app.CurrentImageFullName.Text = app.imageList(id);
                app.ProgressBar.setProgress(id/numel(app.imageList), sprintf("%.1f %%",id*100/numel(app.imageList)));
            
                [patternCorners,cropRegionVertices] = detectPatternCorners(app);
                if isempty(patternCorners) % in case pattern not detected, record it in Excel file                    
                    T.TotalArea = "Pattern Not detected";
                    saveFilePathName = app.OutputpathEditField.Value + "/leaf_info" + app.OutputFormatDropDown.Value;                    

                    if canWrite(saveFilePathName) % in Windows, if the file is open, writing the file will give error, so first do a check
                        writetable(T,saveFilePathName,'WriteRowNames',true,'WriteMode','Append');
                        continue;
                    else
                        message = sprintf('Failed to write to %s\nYou may not have write permission, or the file is open by another application.', saveFilePathName);
                        uialert(app.LeafAnalyzerUIFigure,message,'Error');
                        app.ProgressBar.finishProgress("Error! Failed to write to: " + saveFilePathName);
                        app.Step1SelectmethodpatternimagepathPanel.Enable = "on"; % cannot change after Run button clicked
                        app.Step2SettingsPanel.Enable = "on"; % cannot change settings during running
                        app.RunButton.Enable = "on";
                        return;
                    end
                end

                imshow(app.currentRGB,'Parent',app.UIAxesResult);
                hold(app.UIAxesResult, 'on' );
                plot(app.UIAxesResult,patternCorners(:,2),patternCorners(:,1),'yo','MarkerFaceColor','r', 'MarkerSize',12,'LineWidth',2);
            
%                 fig.Renderer = 'painters'; % render the points as vector, good for zoom-in
                if app.PatterndetectionoverlayCheckBox.Value
                    exportgraphics(app.UIAxesResult,strcat(app.OutputpathEditField.Value,imgName,"_corners.png")); % if there are 2 axes, exportgraphics will make the other axis disappear, this is matlab bug
                end
                
                hold(app.UIAxesResult, 'off' );
                pause(0.01); %  this forces the axis to refresh, or use "drawnow" which updates all UI components

                cornerTopLeft = patternCorners(1,:);
                cornerTopRight = patternCorners(2,:); 
                cornerBotRight = patternCorners(3,:); 
                cornerBotLeft = patternCorners(4,:);                 

                [rows, cols, ~] = size(app.currentRGB);

                if app.PatternTypeA.Value
                    polygonMaskROI = poly2mask(cropRegionVertices(:,2),cropRegionVertices(:,1),rows, cols);
                else
                    polygonMaskROI = poly2mask(patternCorners(:,2),patternCorners(:,1),rows, cols);
                end
                
         
                if app.PerspectiveCorrectionCheckBox.Value()            
                    [H,pixelArea, scaleX, scaleY, polygonMaskROI] = computeHomography(app, patternCorners, cropRegionVertices); %
                    correctedCurrentRGB = imwarp(app.currentRGB, projective2d(H.T), 'OutputView', imref2d(size(app.currentRGB))); 
                    roiRGB = correctedCurrentRGB;
                else
                    roiRGB = app.currentRGB;

                    %----- compute scale -----%
                    frameWidthInPixel = (norm(cornerTopRight-cornerTopLeft) + norm(cornerBotRight-cornerBotLeft))/2;
                    frameHeightInPixel = (norm(cornerBotLeft - cornerTopLeft) + norm(cornerBotRight-cornerTopRight))/2;             

                    boxWidthInMeter = app.PatternWidthEditField.Value;
                    boxHeightInMeter = app.PatternHeightEditField.Value;

                    %scales in X and Y direction, how many meters per pixel
                    scaleX = boxWidthInMeter/frameWidthInPixel;
                    scaleY = boxHeightInMeter/frameHeightInPixel;
                    % pixelArea = scaleX*scaleY; % area per pixel, mm^2

                    verticesQuad = [patternCorners(:,2) patternCorners(:,1)]; % switch from (y,x) to (x,y)
                    patternAreaPixel = quadAreaXY(verticesQuad);
                    pixelArea = (boxWidthInMeter*boxHeightInMeter)/patternAreaPixel; % scale

                end
  
                %----- extract text via OCR -----%
                if app.LabeltextCheckBox.Value                    

                    % Check if pattern is upright
                    if cornerTopLeft(1) > cornerBotRight(1) || cornerTopLeft(2) > cornerBotRight(2) 
                        uialert(app.LeafAnalyzerUIFigure,"Please rotate the image upright for text detection!",'Error');
                        textPlantID = "Error";
                    else
                        % first estimate the text area
                        textHeightPixels = 30/scaleY; % Text height is max 3cm or 30mm
    
                        % calculate approximate text bounding box (bigger
                        % than acutally bbx)
                        [approxTextBbox, ~] = getTextBoundingBox(roiRGB, cornerTopLeft([2 1]), cornerTopRight([2 1]), textHeightPixels); % x,y of corners are switched

                        textBbox = detectTextCRAFT(roiRGB, approxTextBbox);
                        % If multiple boxes (it can happen even if the text is on the same row), merge to one bbox (union)
                        if size(textBbox,1) > 1
                            x1 = min(textBbox(:,1));
                            y1 = min(textBbox(:,2));
                            x2 = max(textBbox(:,1) + textBbox(:,3));
                            y2 = max(textBbox(:,2) + textBbox(:,4));
                            textBbox = [x1, y1, x2-x1, y2-y1];
                        end
                        structOCR = ocr(roiRGB,textBbox, LayoutAnalysis='block', CharacterSet='0123456789ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz-_.'); %,,textRegion,'TextLayout', 'line','Language','english','CharacterSet','0123456789ABCDEFGHIJKLMNOPQRSTUVWXYZabcdefghijklmnopqrstuvwxyz-_.'
                        
                        if isempty(structOCR) || strlength(string(structOCR.Text))==0% in case no text is detected, use file name as ID
                            textPlantID = "Null";
        %                     disp("No text detected in the image!");
        %                     return;
                        else
                            textPlantID = strtrim(structOCR.Text); % trim white spaces
                            % show recognized text, annotate, save
                            imgAnnotate = insertObjectAnnotation(roiRGB,"rectangle", structOCR.WordBoundingBoxes,structOCR.Words,LineWidth=5,FontSize=72);
                            if app.OCRdetectionoverlayCheckBox.Value
                                imwrite(imgAnnotate,strcat(app.OutputpathEditField.Value,imgName,"_ocr.png"));
                            end
                            
                            updateAxisImage(app, imgAnnotate, app.UIAxesResult);
                        end    
                    end

                    T.Text = {textPlantID};
                     
                end
  
                if size(roiRGB,3) == 1 % if it's grayscale
                    roiRGB = repmat(roiRGB, [1, 1, 3]); % repeat grayscale channels to 3
                end

                roiRGB(repmat(~polygonMaskROI,[1 1 3])) = 0; % set the masked-out region to black
                roiHSV = rgb2hsv(roiRGB);
        
                % shrink the ROI to avoid border effects    
                shrinkAmount = app.BorderShrinkEditField.Value; % Number of pixels to shrink
                shrinkedMask = imerode(polygonMaskROI, strel('disk', shrinkAmount));
               
                if app.ClusteringButton.Value
                    %                 distanceImage = getAngularDistanceImage(app, app.currentRGB);

                    saturation = single(roiHSV(:,:,2)); % change type to single, otherwise featureMatrix will be uint8
    
                    imgLab = rgb2lab(roiRGB);
                    L = single(imgLab(:,:,1));
                    b = single(imgLab(:,:,3));
    
                    featureMatrix = cat(3, L, b, saturation);

                    if app.DGCICheckBox.Value
                        H = roiHSV(:,:,1);   % degrees                    
                        V = roiHSV(:,:,3);         % 0–1
                        
                        % dgci = ((V-S)+(1-H)+(1-S))/3;
                       
                        dgci = ((H - 60)/60 + (1 - saturation) + (1 - V)) / 3;
                        dgci = max(0, min(1, dgci));
    
                        featureMatrix = cat(3, featureMatrix, dgci);
                    end
    
                    if app.CCheckBox.Value
                        c = sqrt(single(imgLab(:,:,2)).^2 + single(imgLab(:,:,3)).^2);                   
                        featureMatrix = cat(3, featureMatrix, c);                    
                    end
    
                    if app.ExGCheckBox.Value                    
                        ExG = 2 * roiRGB(:,:,2) - roiRGB(:,:,1) - roiRGB(:,:,3);
                        ExG = single(ExG);
                        featureMatrix = cat(3, featureMatrix, ExG);
                    end
    
                    if app.HRFICheckBox.Value
                        sRGB = single(roiRGB);
                        HRFI = (sRGB(:,:,1) - sRGB(:,:,3)).*(sRGB(:,:,2) - sRGB(:,:,3)); 
                        featureMatrix = cat(3, featureMatrix, HRFI);
                    end
    
                    if app.TextureCheckBox.Value
                        grayImg = rgb2gray(roiRGB);
                        SE = strel("disk",4);
                        localEntropy = entropyfilt(grayImg, SE.Neighborhood); 
                        featureMatrix = cat(3, featureMatrix, localEntropy);
                    end
    
                    if app.TextureMCheckBox.Value
                        grayImg = rgb2gray(roiRGB);
                        grayImg = medfilt2(grayImg,[4,4]);
    
                        SE = strel("disk",4);
                        localEntropy = entropyfilt(grayImg, SE.Neighborhood); 
                        SE = strel("disk",8);
                        localRange = stdfilt(grayImg, SE.Neighborhood);
                        multi = localEntropy .* (1-rescale(localRange)); 
                        
                        SE = strel("disk",10); openMulti = imopen(multi,SE);
                        featureMatrix = cat(3, featureMatrix, openMulti);
                    end
    
                    if app.TextureDCheckBox.Value
                        grayImg = rgb2gray(roiRGB);
                        SE = strel("disk",4);
                        localEntropy = entropyfilt(grayImg, SE.Neighborhood); %figure("Name","entropy4*2");imshow(rescale(localEntropy));
                        SE = strel("disk",8);
    %                     localStd = stdfilt(grayImg, SE.Neighborhood); %figure("Name","std8*2");imshow(rescale(localStd));
                        localRange = stdfilt(grayImg, SE.Neighborhood);
                        diffRange = abs(rescale(localEntropy) - rescale(localRange)); 
                        SE = strel("disk",10); openDiffRange = imopen(diffRange,SE);             
    
                        featureMatrix = cat(3, featureMatrix, openDiffRange);
                    end
    
                    if app.LoGCheckBox.Value
                        imgGray = rgb2gray(roiRGB);
                        h = fspecial("log",3,0.4); 
                        LoG = single(imfilter(imgGray,h));
                        featureMatrix = cat(3, featureMatrix, LoG);
                    end
                                                    
                    maskBW = kmeansCroppedMaskBW(app, featureMatrix, shrinkedMask);                    
        
                elseif app.ThresholdingButton.Value
                    roiRGB(repmat(~shrinkedMask,[1 1 3])) = 0; 
                    [~,maskBW] = hsvSegmentation(roiRGB, app.HMinSlider.Value, app.HMaxSlider.Value, app.SMinSlider.Value,app.SMaxSlider.Value,app.VMinSlider.Value,app.VMaxSlider.Value);
                end          

                if app.FillholesCheckBox.Value
                    maskBW = imfill(maskBW, 'holes');
                end
            
                % remove small non-leaf regions                
                CC = bwconncomp(maskBW); % connected components struct
                if app.PerleafareaCheckBox.Value
                    stats = regionprops(CC, 'Area', 'Centroid'); % if need individual area, get the 'centroid' for printing text
                else
                    stats = regionprops(CC,"Area"); % region property
                end

                maxArea = max([stats.Area]); % biggest leaf area

                idx = find([stats.Area] > app.MinLeafAreaEditField.Value/100.0*maxArea); % region with pixel number obove threshold. idx is region idx not pixel idx
                
%                 idx = intersect(idx2_number,idx1); %find(idx1 & idx2);
                L = labelmatrix(CC);
                onlyLeafBW = ismember(L,idx); % BW with non-leaf region removed

                if app.WatershedCheckBox.Value
                    D = -bwdist(~onlyLeafBW);  % negative so grains become "basins"
    
                    % Suppress shallow minima to reduce over-segmentation
                    D2 = imhmin(D, app.WatershedMinimaSpinner.Value);            % tweak the 1.5 'h' value to taste
                    
                    % Run the watershed transform
                    L = watershed(D2);
                    
                    % Zero-out watershed ridge lines so they become background
                    onlyLeafBW(L == 0) = 0;

                    cc  = bwconncomp(onlyLeafBW);
                    RGBWatershed = label2rgb(labelmatrix(cc),'hsv','k','shuffle');
                    imwrite(RGBWatershed,strcat(app.OutputpathEditField.Value,imgName,"_Watershed.png"));
                end

                if app.BinaryleafmaskCheckBox.Value
                    imwrite(onlyLeafBW,strcat(app.OutputpathEditField.Value,imgName,"_binary_mask.png"));
                end
                
                maskedRGB = roiRGB;
                maskedRGB(repmat(~onlyLeafBW,[1 1 3])) = 0; % Set background pixels where BW is false to zero.
                if app.MaskedRGBimageCheckBox.Value
                    imwrite(maskedRGB,strcat(app.OutputpathEditField.Value,imgName,"_masked_RGB.png"));
                end
                updateAxisImage(app, maskedRGB, app.UIAxesResult);


                if app.UnitsDropDown.Value == "pixel"
                    scaleX = (100/app.ResizeImageEditField.Value);
                    scaleY = scaleX;
                    pixelArea = (100/app.ResizeImageEditField.Value)*(100/app.ResizeImageEditField.Value); % pixelArea becomes a scale factor
                end

                if ~app.CheckBoxUseGrids.Value % not classifying leaves
                    [totalAreaP1,meanAreaP1, maxLeafAreaP1,numLeavesP1, centroids, individualAreas, individualPerimeter,oBBoxMax, boxesAllLeaves] = leafArea(onlyLeafBW, app.PerleafareaCheckBox.Value, app.PerleafperimeterCheckBox.Value, app.MaxleafdimensionsCheckBox.Value, app.PerleafdimensionsCheckBox.Value);
                    
                    if app.TotalareaCheckBox.Value
                        T.TotalArea = sprintf("%.1f",totalAreaP1*pixelArea);
                    end
                    if app.MeanareaCheckBox.Value
                        T.MeanArea = sprintf("%.1f",meanAreaP1*pixelArea);
                    end
                    if app.LeafcountCheckBox.Value
                        T.LeafNum = numLeavesP1;
                    end
                    if app.MaxleafareaCheckBox.Value
                        T.MaxLeafArea = sprintf("%.1f",maxLeafAreaP1*pixelArea);
                    end

                    if app.AreaStdDevCheckBox.Value
                        T.AreaStdDev = sprintf("%.2f", std(individualAreas)*pixelArea); % Note: std(individualAreas*pixelArea) = std(individualAreas)*pixelArea
                    end
                    if app.MaxleafdimensionsCheckBox.Value
                        T.MaxLeafLength = sprintf("%.1f",oBBoxMax(3)*scaleY);
                    end
                    
                    if app.PerleafareaCheckBox.Value                        
%                         [totalAreaP1,meanAreaP1, maxLeafAreaP1,numLeavesP1,oBBoxP1, centroids, individualAreas] = leafArea(onlyLeafBW, true, app.MaxleafdimensionsCheckBox.Value);
%                         areaImage = imwarp(maskedRGB, projective2d(H.T), 'OutputView', imref2d(size(onlyLeafBW)));
                        areaImage = maskedRGB;
                        strPerLeafArea = "";
                        for k = 1:length(individualAreas)
                            % Get the area and centroid of the current component
                            area = individualAreas(k)*pixelArea;
                            currentAreaString = sprintf('%.1f',area);
                            strPerLeafArea = strPerLeafArea + currentAreaString + " ";
                                                        
                            % Convert centroid to integer for text positioning
                            x = round(centroids(2*k-1));
                            y = round(centroids(2*k));
                            
                            % Insert the area as red text in the center of the component                            
                            areaImage = insertText(areaImage, [x y], currentAreaString, 'TextColor', 'white', 'FontSize', 18, 'BoxOpacity', 0);                            
                        end
                        T.PerLeafArea = strPerLeafArea;
                        imwrite(areaImage,strcat(app.OutputpathEditField.Value,imgName,"_areas.png"));                    
                    end

                    if app.PerleafperimeterCheckBox.Value 
                        perimeterImage = maskedRGB;
                        strPerimeters = "";
                        for k = 1:length(individualPerimeter)
                            % Get the area and centroid of the current component
                            perimeter = individualPerimeter(k)*(scaleX+scaleY)/2;                                
                            currentPerimeterString = sprintf('%.1f',perimeter);
                            strPerimeters = strPerimeters + currentPerimeterString + " ";
                                                        
                            % Convert centroid to integer for text positioning
                            x = round(centroids(2*k-1));
                            y = round(centroids(2*k));
                            
                            % Insert the area as red text in the center of the component                            
                            perimeterImage = insertText(perimeterImage, [x y], currentPerimeterString, 'TextColor', 'white', 'FontSize', 18, 'BoxOpacity', 0);                            
                        end
                        T.Perleafperimeter = strPerimeters;
                        imwrite(perimeterImage,strcat(app.OutputpathEditField.Value,imgName,"_perimeters.png"));                    
                    end


                    % show oriented bounding boxes and annotations
                    if app.MaxleafdimensionsCheckBox.Value
                        text = sprintf('L:%.1f W:%.1f',oBBoxMax(3)*scaleY,oBBoxMax(4)*scaleX);
                        T.Maxleafdimensions = sprintf("(%.1f,%.1f) ",oBBoxMax(3)*scaleY,oBBoxMax(4)*scaleX);
%                         onlyLeafBWRGB = 255 * repmat(uint8(onlyLeafBW),1,1,3);
                        annotationPosition = [oBBoxMax(1) oBBoxMax(2)];
                        onlyLeafAnnotation = insertText(maskedRGB,annotationPosition,text,'AnchorPoint','center',FontSize=48,BoxOpacity=0.4,TextColor="red");
                                       
                        updateAxisImage(app, onlyLeafAnnotation, app.UIAxesResult);
                        hold(app.UIAxesResult, 'on' );
                        drawOrientedBox(app.UIAxesResult, oBBoxMax, 'linewidth', 1, 'color', 'g');
                        hold(app.UIAxesResult, 'off' );
                    end

                    if app.PerleafdimensionsCheckBox.Value                        
                        dimensionsImage = maskedRGB;
                        strPerLeafDimensions = "";
                        for k = 1:size(boxesAllLeaves,1)
                            text = sprintf('L:%.0f W:%.0f',boxesAllLeaves(k,3)*scaleY,boxesAllLeaves(k,4)*scaleX);
                            strPerLeafDimensions = strPerLeafDimensions + sprintf('(%.0f,%.0f) ',boxesAllLeaves(k,3)*scaleY,boxesAllLeaves(k,4)*scaleX);

                            T.PerLeafDimensions = strPerLeafDimensions;
                                                        
                            annotationPosition = [boxesAllLeaves(k,1) boxesAllLeaves(k,2)];
                            dimensionsImage = insertText(dimensionsImage,annotationPosition,text,'AnchorPoint','center',FontSize=48,BoxOpacity=0.4,TextColor="red");                        
                        end
                        updateAxisImage(app, dimensionsImage, app.UIAxesResult);
                        hold(app.UIAxesResult, 'on' );
                        for k = 1:size(boxesAllLeaves,1)                            
                            drawOrientedBox(app.UIAxesResult, boxesAllLeaves(k,:), 'linewidth', 1, 'color', 'g');
                        end
                        hold(app.UIAxesResult, 'off' );
                        exportgraphics(app.UIAxesResult,strcat(app.OutputpathEditField.Value,imgName,"_dimensions.png"));                   
                    end

                    if app.MeangreenleafindexCheckBox.Value || app.PerleafgreenindexCheckBox.Value
                        R = double(app.currentRGB(:,:,1));
                        G = double(app.currentRGB(:,:,2));
                        B = double(app.currentRGB(:,:,3));
                    
                        denom = (2*G + R + B);
                        epsv  = 1e-12;
                        gli = (2*G - R - B) ./ max(denom, epsv);
                        % Clip to [-1, 1] for numerical safety
                        gli = max(-1, min(1, gli));
                    
                        if app.MeangreenleafindexCheckBox.Value
                            gliMean = mean(gli(onlyLeafBW), 'omitnan');  
                            T.MeanGreenLeafIndex = sprintf("%.2f",gliMean);
                        end
                    

                        if app.PerleafgreenindexCheckBox.Value
                            stats = regionprops(onlyLeafBW,["Centroid","PixelIdxList"]);
                            perLeafGLI = zeros(numel([stats.Centroid])/2,1); % each centroid has two elements 
                            gliImage = maskedRGB;
                            for k = 1:length(perLeafGLI)
                                perLeafGLI(k) = mean(gli(stats(k).PixelIdxList), 'omitnan');

                                % Convert centroid to integer for text positioning
                                x = round(stats(k).Centroid(1));
                                y = round(stats(k).Centroid(2));
                                
                                % Insert the area as red text in the center of the component                            
                                gliImage = insertText(gliImage, [x y], sprintf('%.2f',perLeafGLI(k)), 'TextColor', 'white', 'FontSize', 18, 'BoxOpacity', 0);
                            end                      
                            imwrite(gliImage,strcat(app.OutputpathEditField.Value,imgName,"_gli.png"));
                            T.PerLeafGreenIndex = sprintf("%.2f ",perLeafGLI); % round to 2 dp

                        end
                    end
                
                    
                    % if app.LabeltextCheckBox.Value
                    %     T.PlantID = {textPlantID};
                    % end
                    % T.ImageID = {imgName};
                    % 
                    % if app.TotalareaCheckBox.Value
                    %     T.TotalArea = sprintf("%.1f",totalAreaP1*pixelArea);
                    % end
                    % if app.MeanareaCheckBox.Value
                    %     T.MeanArea = sprintf("%.1f",meanAreaP1*pixelArea);
                    % end
                    % if app.LeafcountCheckBox.Value
                    %     T.LeafNum = numLeavesP1;
                    % end
                    % if app.MaxleafareaCheckBox.Value
                    %     T.MaxLeafArea = sprintf("%.1f",maxLeafAreaP1*pixelArea);
                    % end
                    % if app.AreaStdDevCheckBox.Value
                    %     T.AreaStdDev = sprintf("%.2f", std(individualAreas*pixelArea));
                    % end
                    % if app.MaxleafdimensionsCheckBox.Value
                    %     T.MaxLeafLength = sprintf("%.1f",oBBoxMax(3)*scaleY);
                    % end
                    

                    saveFilePathName = app.OutputpathEditField.Value + "/leaf_info" + app.OutputFormatDropDown.Value;
                    

                    if canWrite(saveFilePathName) % in Windows, if the file is open, writing the file will give error, so first do a check
                        %                     T = table(PlantID,ImageID,P1Area,P1MeanArea,P1Num,P1MaxLeafArea,P1MaxLeafLength);
                        writetable(T,saveFilePathName,'WriteRowNames',true,'WriteMode','Append');
                        continue; % skip code below for computing subcategories
                    else
                        message = sprintf('Failed to write to %s\nYou may not have write permission, or the file is opened by another application.', saveFilePathName);
                        uialert(app.LeafAnalyzerUIFigure,message,'Error');
                        app.ProgressBar.finishProgress("Error! Failed to write to: " + saveFilePathName);
                        app.Step1SelectmethodpatternimagepathPanel.Enable = "on"; % cannot change after Run button clicked
                        app.Step2SettingsPanel.Enable = "on"; % cannot change settings during running
                        app.RunButton.Enable = "on";
                        return;
                    end
                end

                continue; % skip calculating info for each grid, the below code will not be implemented

                topMiddle = (cornerTopLeft+cornerTopRight)/2;
                botMiddle = (cornerBotLeft+cornerBotRight)/2;
                
                polygonP1 = [cornerTopLeft;topMiddle; topMiddle+(botMiddle-topMiddle)/3;  cornerTopLeft+(cornerBotLeft-cornerTopLeft)/3];
                polygonMaskP1 = poly2mask(polygonP1(:,2),polygonP1(:,1),rows, columns);
                
                polygonP2 = [polygonP1(4,:);polygonP1(3,:); topMiddle+(botMiddle-topMiddle)*2/3; cornerTopLeft+(cornerBotLeft-cornerTopLeft)*2/3];
                polygonMaskP2 = poly2mask(polygonP2(:,2),polygonP2(:,1),rows, columns);
            
                polygonP3 = [polygonP2(4,:);polygonP2(3,:); botMiddle; cornerBotLeft];
                polygonMaskP3 = poly2mask(polygonP3(:,2),polygonP3(:,1),rows, columns);
            
                polygonS1 = [topMiddle;cornerTopRight; cornerTopRight+(cornerBotRight-cornerTopRight)/3;polygonP1(3,:)];
                polygonMaskS1 = poly2mask(polygonS1(:,2),polygonS1(:,1),rows, columns);
            
                polygonS2 = [polygonS1(4,:);polygonS1(3,:); cornerTopRight+(cornerBotRight-cornerTopRight)*2/3; polygonP2(3,:)];
                polygonMaskS2 = poly2mask(polygonS2(:,2),polygonS2(:,1),rows, columns);
            
                polygonS3 = [polygonS2(4,:);polygonS2(3,:); cornerBotRight; botMiddle];
                polygonMaskS3 = poly2mask(polygonS3(:,2),polygonS3(:,1),rows, columns);
            
                %----- crop and compute leaf info for each grid -----%
                BWCroppedP1 = onlyLeafBW & polygonMaskP1;
                [totalAreaP1,meanAreaP1,maxLeafAreaP1,numLeavesP1,oBBoxP1, ~,~] = leafArea(BWCroppedP1);
            
            %     imshow(BWCroppedP1); 
            
                BWCroppedP2 = onlyLeafBW & polygonMaskP2;
                [totalAreaP2,meanAreaP2,maxLeafAreaP2,numLeavesP2,oBBoxP2, ~,~] = leafArea(BWCroppedP2);
            
                BWCroppedP3 = onlyLeafBW & polygonMaskP3;
                [totalAreaP3,meanAreaP3,maxLeafAreaP3,numLeavesP3,oBBoxP3, ~,~] = leafArea(BWCroppedP3);
            
                BWCroppedS1 = onlyLeafBW & polygonMaskS1;
                [totalAreaS1,meanAreaS1,maxLeafAreaS1,numLeavesS1,oBBoxS1, ~,~] = leafArea(BWCroppedS1);
            
                BWCroppedS2 = onlyLeafBW & polygonMaskS2;
                [totalAreaS2,meanAreaS2,maxLeafAreaS2,numLeavesS2,oBBoxS2, ~,~] = leafArea(BWCroppedS2);
            
                BWCroppedS3 = onlyLeafBW & polygonMaskS3;
                [totalAreaS3,meanAreaS3,maxLeafAreaS3,numLeavesS3,oBBoxS3, ~,~] = leafArea(BWCroppedS3); 

            
                fprintf('numLeaves= %d, Total area=%d, Max area=%d\n',numLeavesP3, totalAreaP3, maxLeafAreaP3);
                                      
                                
                if app.MaxleafdimensionsCheckBox.Value
                    % concatenate oriented bounding boxes
                    oBBox = [];
                    annotationText = {};
                    if numLeavesP1 > 0
                        oBBox = [oBBox; oBBoxP1];
                    end
                    if numLeavesP2 > 0
                        oBBox = [oBBox; oBBoxP2];
                    end
                    if numLeavesP3 > 0
                        oBBox = [oBBox; oBBoxP3];
                    end
                    if numLeavesS1 > 0
                        oBBox = [oBBox; oBBoxS1];
                    end
                    if numLeavesS2 > 0
                        oBBox = [oBBox; oBBoxS2];
                    end
                    if numLeavesS3 > 0
                        oBBox = [oBBox; oBBoxS3];
                    end                
                    
                    for iBox = 1:size(oBBox,1)
                        if app.UnitsDropDown.Value == "pixel"
                            text = sprintf('L:%.0f W:%.0f',oBBox(iBox,3),oBBox(iBox,4));
                        else
                            text = sprintf('L:%.0f W:%.0f',oBBox(iBox,3)*scaleY,oBBox(iBox,4)*scaleX);
                        end
                        annotationText{end+1} = text;
                    end

                    % show oriented bounding boxes and annotations
                    annotationPosition = [oBBox(:,1) oBBox(:,2)];
                    onlyLeafAnnotation = insertText(maskedRGB,annotationPosition,annotationText,'AnchorPoint','center',FontSize=88,BoxOpacity=0.4,TextColor="red");
                    updateAxisImage(app, onlyLeafAnnotation, app.UIAxesResult);

                    hold(app.UIAxesResult, 'on' );
                    drawOrientedBox(app.UIAxesResult, oBBox, 'linewidth', 2, 'color', 'g');
                    hold(app.UIAxesResult, 'off' );
                end
                                                
            
                T = table;
                if app.LabeltextCheckBox.Value
                    T.PlantID = {textPlantID};
                end
                T.ImageID = {imgName};
                
                if app.TotalareaCheckBox.Value
                    T.P1Area = round([totalAreaP1*pixelArea]);
                end
                if app.MeanareaCheckBox.Value
                    T.P1MeanArea = round([meanAreaP1*pixelArea]);
                end
                if app.LeafcountCheckBox.Value
                    T.P1Num = numLeavesP1;
                end
                if app.MaxleafareaCheckBox.Value
                    T.P1MaxLeafArea = round([maxLeafAreaP1*pixelArea]);
                end
                if app.MaxleafdimensionsCheckBox.Value
                    T.P1MaxLeafLength = round([oBBoxP1(3)*scaleY]);
                end
                    
                if app.TotalareaCheckBox.Value
                    T.P2Area = round([totalAreaP2*pixelArea]);
                end
                if app.MeanareaCheckBox.Value
                    T.P2MeanArea = round([meanAreaP2*pixelArea]); 
                end
                if app.LeafcountCheckBox.Value
                    T.P2Num = numLeavesP2;
                end
                if app.MaxleafareaCheckBox.Value
                    T.P2MaxLeafArea = round([maxLeafAreaP2*pixelArea]);
                end
                if app.MaxleafdimensionsCheckBox.Value
                    T.P2MaxLeafLength = round([oBBoxP2(3)*scaleY]);
                end

                if app.TotalareaCheckBox.Value
                    T.P3Area = round([totalAreaP3*pixelArea]);
                end
                if app.MeanareaCheckBox.Value
                    T.P3MeanArea = round([meanAreaP3*pixelArea]);
                end
                if app.LeafcountCheckBox.Value
                    T.P3Num = numLeavesP3;
                end
                if app.MaxleafareaCheckBox.Value
                    T.P3MaxLeafArea = round([maxLeafAreaP3*pixelArea]);
                end
                if app.MaxleafdimensionsCheckBox.Value
                    T.P3MaxLeafLength = round([oBBoxP3(3)*scaleY]);
                end

                if app.TotalareaCheckBox.Value
                    T.S1Area = round([totalAreaS1*pixelArea]);
                end
                if app.MeanareaCheckBox.Value
                    T.S1MeanArea = round([meanAreaS1*pixelArea]);
                end
                if app.LeafcountCheckBox.Value
                    T.S1Num = numLeavesS1;
                end
                if app.MaxleafareaCheckBox.Value
                    T.S1MaxLeafArea = round([maxLeafAreaS1*pixelArea]);
                end
                if app.MaxleafdimensionsCheckBox.Value
                    T.S1MaxLeafLength = round([oBBoxS1(3)*scaleY]);
                end

                if app.TotalareaCheckBox.Value
                    T.S2Area = round([totalAreaS2*pixelArea]);
                end
                if app.MeanareaCheckBox.Value
                    T.S2MeanArea = round([meanAreaS2*pixelArea]);
                end
                if app.LeafcountCheckBox.Value
                    T.S2Num = numLeavesS2;
                end
                if app.MaxleafareaCheckBox.Value
                    T.S2MaxLeafArea = round([maxLeafAreaS2*pixelArea]);
                end
                if app.MaxleafdimensionsCheckBox.Value
                    T.S2MaxLeafLength = round([oBBoxS2(3)*scaleY]);
                end

                if app.TotalareaCheckBox.Value
                    T.S3Area = round([totalAreaS3*pixelArea]);
                end
                if app.MeanareaCheckBox.Value
                    T.S3MeanArea = round([meanAreaS3*pixelArea]);
                end
                if app.LeafcountCheckBox.Value
                    T.S3Num = numLeavesS3;
                end
                if app.MaxleafareaCheckBox.Value
                    T.S3MaxLeafArea = round([maxLeafAreaS3*pixelArea]);
                end
                if app.MaxleafdimensionsCheckBox.Value
                    T.S3MaxLeafLength = round([oBBoxS3(3)*scaleY]);
                end
                
            
                saveFilePathName = app.OutputpathEditField.Value + "/leaf_info" + app.OutputFormatDropDown.Value;
                               
                if canWrite(saveFilePathName) % in Windows, if the file is open, writing the file will give error, so first do a check
                    writetable(T,saveFilePathName,'WriteRowNames',true,'WriteMode','Append');
                else
                    message = sprintf('Failed to write to %s\nYou may not have write permission, or the file is open by another application.', saveFilePathName);
                    uialert(app.LeafAnalyzerUIFigure,message,'Error');
                    app.ProgressBar.finishProgress("Error! Failed to write to: " + saveFilePathName);
                    app.Step1SelectmethodpatternimagepathPanel.Enable = "on"; % cannot change after Run button clicked
                    app.Step2SettingsPanel.Enable = "on"; % cannot change settings during running
                    app.RunButton.Enable = "on";
                    return;
                end
            
            
            end % image file loop ends

            elapsed = toc;                     % stop timer, seconds
            fprintf('Elapsed time: %.6f s\n', elapsed);

            app.ProgressBar.finishProgress("Done! Results saved to: " + saveFilePathName);
            app.Step1SelectmethodpatternimagepathPanel.Enable = "on"; % cannot change after Run button clicked
            app.Step2SettingsPanel.Enable = "on"; % cannot change settings during running
            app.RunButton.Enable = "on"; 
        end

        % Size changed function: LeafAnalyzerUIFigure, LeafAreaTab
        function LeafAreaTabSizeChanged(app, event)
            app.Step2SettingsPanel.Position(2) = app.Step3RunPanel.Position(2);
            
        end

        % Button pushed function: SelectImageButton_HSI
        function SelectImageButton_HSIPushed(app, event)
            [file,path] = uigetfile('*.hdr;*.bil',"Select a hyperspectral file", app.lastDirectory);
            preventMainAppWindowMinimized(app);

            if ~file                 
                return;
            end
            app.lastDirectory = path;

            [~,imgName,ext] = fileparts(file);

            if ext == ".hdr"
                if ~exist(fullfile(path,imgName+".bil"), 'file')
                    uialert(app.LeafAnalyzerUIFigure,".bil file not found!",'Error');
                    return;
                end
            elseif ext == ".bil"
                if ~exist(fullfile(path,imgName+".hdr"), 'file')
                    uialert(app.LeafAnalyzerUIFigure,".hdr file not found!",'Error');
                    return;
                end
            end

            app.hCube = parseHdrFile(fullfile(path,imgName+".hdr"));
           
            % if pattern, extract mask
            if app.DetectPatternCheckBox.Value

                if app.hCube.Wavelength(1) > 450 % no R,G,B
                    uialert(app.LeafAnalyzerUIFigure,"No RGB wavelengths!",'Error');
                    return;
                else
                    coloredImage = colorize(app.hCube,method="rgb");
                end
                
                [id,loc] = readAprilTag(coloredImage,"tag36h11");%"tag16h5", it will read the image from left to right and from up to bottom, so the 1st will be the topleft in the image                
                if ~(ismember(0, id) && ismember(1, id) && ismember(2, id) && ismember(3, id)) % all tags must be detected, id from 0 to 3
                    uialert(app.LeafAnalyzerUIFigure,"No pattern detected!",'Error');
                    return;
                end
                indices = find(id == 0); % top left ApriTag
                cornerTopLeft = [loc(4,2,indices(1)) loc(4,1,indices(1))]; % loc is 4X2X4, or (x,y,id), 4th corner of tag is topleft
                indices = find(id == 1); % top right ApriTag
                cornerTopRight = [loc(3,2,indices(1)) loc(3,1,indices(1))]; % 3rd corner of tag is topright
                indices = find(id == 2); % bot left ApriTag
                cornerBotLeft = [loc(1,2,indices(1)) loc(1,1,indices(1))]; % 1st corner of tag is botleft
                indices = find(id == 3); % bot right ApriTag
                cornerBotRight = [loc(2,2,indices(1)) loc(2,1,indices(1))]; % 2nd corner of tag is botright
                patternCorners = [cornerTopLeft;cornerTopRight; cornerBotRight; cornerBotLeft];

                [rows, cols, ~] = size(coloredImage);
                polygonMaskFrame = poly2mask(patternCorners(:,2),patternCorners(:,1),rows, cols);
                croppedRGB = coloredImage;    
                croppedRGB(repmat(~polygonMaskFrame,[1 1 3])) = 0;         
      
                    
                X = rgb2lab(croppedRGB);
                L = imsegkmeans(single(X),2,'NumAttempts',2); % 2 clusters: background, foreground
%                 L = imsegkmeans(featureSet,2,"NormalizeInput",true);

                props = regionprops(L, 'ConvexArea');
                convex_areas = [props.ConvexArea];
                sorted_convex_areas = sort(convex_areas, 'ascend');
                
                % Find the index of the label with the 2nd largest convex area
                leaf_label = find(convex_areas == sorted_convex_areas(1));  % label of leaf regions, leaf regions will have the smallest convex area
                maskBW = L== leaf_label;
                maskBW = imfill(maskBW, 'holes');

            end

            axis(app.UIAxesHyperResult, 'image'); % this ensures the image fills the whole axis regardless of distortion. if not, some blank will show

            if app.RGBIndexButton.Value
%                 hcube = denoiseNGMeet(hcube);
                if app.hCube.Wavelength(1) > 700 % no visible wavelengths, cannot display in rgb, use default "false color" method
                    coloredImage = colorize(app.hCube);
                else
                    coloredImage = colorize(app.hCube,method="rgb");
                end
                hHyperImage = imshow(coloredImage,'Parent',app.UIAxesHyperResult);
                
%                 imwrite(coloredImage, "003color.png");
            elseif app.NDVIIndexButton.Value
                indices = spectralIndices(app.hCube,"NDVI"); 
                ndviImg = indices.IndexImage;                
                hHyperImage = imagesc(ndviImg,'Parent',app.UIAxesHyperResult);
            elseif app.EVIIndexButton.Value
                indices = spectralIndices(app.hCube);
                eviImg = indices(2).IndexImage;
                eviImg(isinf(eviImg)) = NaN;
                imshow(eviImg);

                indices = spectralIndices(app.hCube,"EVI"); 
                eviImg = indices.IndexImage;
                hHyperImage = imagesc(eviImg,'Parent',app.UIAxesHyperResult);
                
            elseif app.MCARIIndexButton.Value
                indices = spectralIndices(app.hCube,"MCARI"); 
                eviImg = indices.IndexImage;
                hHyperImage = imagesc(eviImg,'Parent',app.UIAxesHyperResult); 
            end

            set(hHyperImage, 'ButtonDownFcn', @(src, evt)app.hyperImageButtonDownCallback(src, evt));
            
        end

        % Size changed function: Step3RunPanel
        function Step3RunPanelSizeChanged(app, event)
%             uialert(app.LeafAnalyzerUIFigure,"Size changed!",'Error');          
            
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            
            app.UIAxesDamagedLeaf.Title.String = "";
            app.UIAxesCluster1.Title.String = "";
            app.UIAxesCluster2.Title.String = "";
            app.UIAxesCluster3.Title.String = "";
            cla(app.UIAxesDamagedLeaf); 
            cla(app.UIAxesCluster1); 
            cla(app.UIAxesCluster2); 
            cla(app.UIAxesCluster3);
            app.DrawMarginButton.Visible = "off";
            app.DrawROIButton.Visible = "off";
            
            if app.TypeDiseasedButton.Value
                app.UIAxesDamagedLeaf.Visible = "on";
                app.UIAxesCluster1.Visible = "on";
                app.UIAxesCluster2.Visible = "on";
                app.UIAxesCluster3.Visible = "on";
                initializeAxis(app,app.UIAxesDamagedLeaf);
                initializeAxis(app,app.UIAxesCluster1);
                initializeAxis(app,app.UIAxesCluster2);
                initializeAxis(app,app.UIAxesCluster3); 
                app.UIAxesDamagedLeaf.Position = [0.1 0.55 0.35 0.35];
                app.UIAxesCluster1.Position = [0.55 0.55 0.35 0.35]; 
                app.UIAxesCluster2.Position = [0.1 0.1 0.35 0.35]; 
                app.UIAxesCluster3.Position = [0.55 0.1 0.35 0.35]; 
            else
                app.UIAxesDamagedLeaf.Visible = "on";
                app.UIAxesCluster1.Visible = "on";
                app.UIAxesCluster2.Visible = "off"; 
                app.UIAxesCluster3.Visible = "off"; 
                initializeAxis(app,app.UIAxesDamagedLeaf);
                initializeAxis(app,app.UIAxesCluster1);
                app.UIAxesDamagedLeaf.Position = [0.04 0.1 0.44 0.8];
                app.UIAxesCluster1.Position = [0.52 0.1 0.44 0.8];  
  
             end
            
        end

        % Button pushed function: DrawMarginButton
        function DrawMarginButtonPushed(app, event)

            if app.DrawMarginButton.Text == "Draw Margin"
                h = images.roi.Freehand(app.UIAxesCluster1, 'Closed',false, 'LineWidth',1); % to allow next drawing
                app.freeHandDrawings = [app.freeHandDrawings,h]; % append new drawing,if nothing actually drawn, h is empty freehand object
                delete(app.freeHandListener); % delete existing listener from past events
                app.freeHandListener = addlistener(h,'DrawingFinished',@(src,evt)app.computeHerbRate(src,evt));            
                draw(h);
            elseif app.DrawMarginButton.Text == "Remove Margin"
                for i = 1: length(app.freeHandDrawings)
                    delete(app.freeHandDrawings(i));
                end
                delete(app.freeHandListener); % if not, will callback after this button pushed
                app.freeHandMask = zeros(size(app.BWGrazedLeaf)); % remove mask as well
                            
                BWFilledRecoveredLeaf = imfill(app.BWGrazedLeaf, 'holes');
                
                herbivoryRate = 1 - double(nnz(app.BWGrazedLeaf))/nnz(BWFilledRecoveredLeaf);    
                BWHoles = BWFilledRecoveredLeaf - app.BWGrazedLeaf;
                
                yellowMask = cat(3, BWHoles, BWHoles, zeros(size(BWHoles)));
                hold(app.UIAxesCluster1, 'on' );
                delete(app.yellowMaskGrazedLeaf); % delete yellow masks created by drawings
                app.yellowMaskGrazedLeaf = imshow(yellowMask,'Parent',app.UIAxesCluster1);
                set(app.yellowMaskGrazedLeaf, 'AlphaData', 0.5 * BWHoles);
                app.UIAxesCluster1.Title.String = sprintf("Herbivory rate: %.1f%%",herbivoryRate*100);                    
                hold(app.UIAxesCluster1, 'off' );
                app.DrawMarginButton.Text = "Draw Margin";
            end            

        end

        % Button pushed function: SelectImageButton_2
        function SelectImageButton_2Pushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg; *.JPG; *.jpeg; *.JPEG; *.png; *.PNG; *.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF'}, 'Select an Image File', app.lastDirectory);
            
            preventMainAppWindowMinimized(app);
            
            if pathname 
                app.PathText.Text = pathname;
                app.lastDirectory = pathname;
            else % in case user open the selection dialogue but cancel it
                return;
            end

            % a new task, clear previous progress
            app.CurrentImageFullName.Text = filename;
%             app.ProgressBar.setProgress(0);
            app.ProgressBar.startProgress("Press 'Run' button to start...");

            
            app.imageList = [string(fullfile(pathname, filename))]; %fullfile returns a single quote string '' instead of ""
            app.NumberOfImagesText.Text = '1';
            app.currentRGB = imreadort(fullfile(pathname, filename)); % imreadort sorts issues with image orientation for iPhone            
            setResizeRatio(app, app.currentRGB);
            app.currentRGB = imresize(app.currentRGB, app.ResizeImageEditField.Value/100.0);
            app.currentFileName = filename;

            updateAxisImage(app, app.currentRGB, app.UIAxesSource);
        
            app.Step2SettingsPanel.Enable = "on";
            app.Step3RunPanel.Enable = "on";
            mkdir(pathname+"/output"); % create a folder for outputs
            app.OutputpathEditField.Value = pathname+"/output/";

            
            doSegmentation(app);    
%                 updateAxisImage(app,app.currentRGB, app.UIAxesSource);
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            selpath = uigetdir;
            preventMainAppWindowMinimized(app);
            if selpath 
                app.OutputpathEditField.Value = selpath;
            end
        end

        % Value changed function: HMinValue
        function HMinValueValueChanged(app, event)
            app.HMinSlider.Value = app.HMinValue.Value;
            doSegmentation(app);
        end

        % Value changed function: HMaxValue
        function HMaxValueValueChanged(app, event)
            app.HMaxSlider.Value = app.HMaxValue.Value;
            doSegmentation(app);
        end

        % Value changed function: SMinValue
        function SMinValueValueChanged(app, event)
            if event.Value > app.SMaxSlider.Value
                app.SMinSlider.Value = app.SMaxSlider.Value;
            else
                app.SMinSlider.Value = event.Value;
            end            
            doSegmentation(app);            
        end

        % Value changed function: SMaxValue
        function SMaxValueValueChanged(app, event)
            if event.Value < app.SMinSlider.Value
                app.SMaxSlider.Value = app.SMinSlider.Value;
            else
                app.SMaxSlider.Value = event.Value;
            end            
            doSegmentation(app);            
        end

        % Value changed function: VMinValue
        function VMinValueValueChanged(app, event)
            if event.Value > app.VMaxSlider.Value
                app.VMinSlider.Value = app.VMaxSlider.Value;
            else
                app.VMinSlider.Value = event.Value;
            end
            doSegmentation(app);            
        end

        % Value changed function: VMaxValue
        function VMaxValueValueChanged(app, event)
            if event.Value < app.VMinSlider.Value
                app.VMaxSlider.Value = app.VMinSlider.Value;
            else
                app.VMaxSlider.Value = event.Value;
            end
            doSegmentation(app);            
        end

        % Callback function
        function SMinValueValueChanged2(app, event)
            
            
        end

        % Selection changed function: SegMethodButtonGroup
        function SegMethodButtonGroupSelectionChanged(app, event)
% %             selectedButton = app.SegMethodButtonGroup.SelectedObject;

            if app.ClusteringButton.Value                
                tabComponents = app.SettingsTabGroup.Children(3).Children;                
                set(tabComponents, 'Enable', 'off'); % Disable the components within the tab
                tabComponents = app.SettingsTabGroup.Children(2).Children;                
                set(tabComponents, 'Enable', 'on'); % Enable the components within the tab
            elseif app.ThresholdingButton.Value
                tabComponents = app.SettingsTabGroup.Children(2).Children;                
                set(tabComponents, 'Enable', 'off'); % Disable the components within the tab 
                tabComponents = app.SettingsTabGroup.Children(3).Children;                
                set(tabComponents, 'Enable', 'on'); % Enable the components within the tab
            end

            if ~isempty(app.currentRGB)
                doSegmentation(app); 
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
             [fileName, pathName] = uiputfile('*.txt', 'Save As');
             preventMainAppWindowMinimized(app);
    
            % Check if the user clicked "Cancel" in the file dialog
            if fileName == 0
                return; % Exit the function if no file was selected
            end  

            fileID = fopen(fullfile(pathName, fileName), 'w');
            fprintf(fileID, 'HMin: %.3f\n', app.HMinValue.Value);
            fprintf(fileID, 'HMax: %.3f\n', app.HMaxValue.Value);
            fprintf(fileID, 'SMin: %.3f\n', app.SMinValue.Value);
            fprintf(fileID, 'SMax: %.3f\n', app.SMaxValue.Value);
            fprintf(fileID, 'VMin: %.3f\n', app.VMinValue.Value);
            fprintf(fileID, 'VMax: %.3f\n', app.VMaxValue.Value);
            fprintf(fileID, 'MinLeafAreaPercent: %f\n', app.MinLeafAreaEditField.Value);
            
            fclose(fileID);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*.txt', 'Select File');
            preventMainAppWindowMinimized(app);
    
            % Check if the user clicked "Cancel" in the file dialog
            if fileName == 0
                return; % Exit the function if no file was selected
            end   
    
   
            fileID = fopen(fullfile(pathName, fileName), 'r');
            
            % Read the values from the file
            values = textscan(fileID, 'HMin: %f');
            % Check if any values were found in the file
            if isempty(values)
                return; % Exit the function if no values were found
            end
            app.HMinSlider.Value = values{1}(1);
            app.HMinValue.Value = values{1}(1);
            values = textscan(fileID, 'HMax: %f');
            app.HMaxSlider.Value = values{1}(1);
            app.HMaxValue.Value = values{1}(1);
        
            values = textscan(fileID, 'SMin: %f');
            app.SMinSlider.Value = values{1}(1);
            app.SMinValue.Value = values{1}(1);
            values = textscan(fileID, 'SMax: %f');
            app.SMaxSlider.Value = values{1}(1);
            app.SMaxValue.Value = values{1}(1);
        
            values = textscan(fileID, 'VMin: %f');
            app.VMinSlider.Value = values{1}(1);
            app.VMinValue.Value = values{1}(1);
            values = textscan(fileID, 'VMax: %f');
            app.VMaxSlider.Value = values{1}(1);
            app.VMaxValue.Value = values{1}(1);

            values = textscan(fileID, 'MinLeafAreaPercent: %f');
            app.MinLeafAreaEditField.Value = values{1}(1);
            
            % Close the file
            fclose(fileID); 

            doSegmentation(app);             
    
        end

        % Value changed function: MinLeafAreaEditField
        function MinLeafAreaEditFieldValueChanged(app, event)
            doSegmentation(app); 
            
        end

        % Callback function
        function UIAxesDamagedLeafButtonDown(app, event)
            
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            % no need to delete ROI and associated listeners created at previous steps, because when a new image is loaded, the 
            % rectangle disppears and the ROI and associated listeners will             % be destroyed automatically
            h = images.roi.Rectangle(app.UIAxesDamagedLeaf, 'Color','red', 'LineWidth',1); 
%             addlistener(h,'DrawingFinished',@(src,evt)app.roiFinishedGrazedLeafCallback(src,evt)); % redundent, ROIMoved will be called after finish
            addlistener(h,'ROIMoved',@(src,evt)app.roiMovedGrazedLeafCallback(src,evt)); 
            addlistener(h,'DeletingROI',@(src,evt)app.roiDeletedGrazedLeafCallback(src,evt)); 
            draw(h);
        end

        % Selection changed function: IndicesButtonGroup
        function IndicesButtonGroupSelectionChanged(app, event)
%             selectedButton = app.IndicesButtonGroup.SelectedObject;
            if isempty(app.hCube)
                return;
            end
            if app.RGBIndexButton.Value
%                 hcube = denoiseNGMeet(hcube);
                if app.hCube.Wavelength(1) > 700 % no visible wavelengths, cannot display in rgb, use default "false color" method
                    coloredImage = colorize(app.hCube);
                else
                    coloredImage = colorize(app.hCube,method="rgb");
                end
                hHyperImage = imshow(coloredImage,'Parent',app.UIAxesHyperResult);                
            elseif app.NDVIIndexButton.Value
                indices = spectralIndices(app.hCube,"NDVI"); 
                ndviImg = indices.IndexImage;
                hHyperImage = imagesc(ndviImg,'Parent',app.UIAxesHyperResult);
            elseif app.EVIIndexButton.Value
                indices = spectralIndices(app.hCube,"EVI"); 
                eviImg = indices.IndexImage;
                hHyperImage = imagesc(eviImg,'Parent',app.UIAxesHyperResult);   
            elseif app.MCARIIndexButton.Value
                indices = spectralIndices(app.hCube,"MCARI"); 
                eviImg = indices.IndexImage;
                hHyperImage = imagesc(eviImg,'Parent',app.UIAxesHyperResult); 
            end

            set(hHyperImage, 'ButtonDownFcn', @(src, evt)app.hyperImageButtonDownCallback(src, evt));
            
        end

        % Button down function: UIAxesHyperResult
        function UIAxesHyperResultButtonDown(app, event)

        end

        % Selection changed function: PatternTypeButtonGroup
        function PatternTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PatternTypeButtonGroup.SelectedObject;
            if app.PatternTypeA.Value
                app.AprilTagLengthEditField.Enable = "on";
            else
                app.AprilTagLengthEditField.Enable = "off";
            end

            if app.PatternTypeC.Value
                app.CheckBoxUseGrids.Enable = "on";
                if app.CheckBoxUseGrids.Value % if use grids, disable individual area and std dev calculation
                    app.PerleafareaCheckBox.Enable = "off";
                    app.AreaStdDevCheckBox.Enable = "off";
                else
                    app.PerleafareaCheckBox.Enable = "on";
                    app.AreaStdDevCheckBox.Enable = "on";
                end
            else
                app.CheckBoxUseGrids.Enable = "off";
                app.PerleafareaCheckBox.Enable = "on";
                app.AreaStdDevCheckBox.Enable = "on";
            end
        end

        % Value changed function: PatternWidthEditField
        function PatternWidthEditFieldValueChanged(app, event)
            app.isPatternSizeSet = true;
        end

        % Value changed function: PatternHeightEditField
        function PatternHeightEditFieldValueChanged(app, event)
            app.isPatternSizeSet = true;            
        end

        % Callback function
        function ColorSpaceButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ColorSpaceButtonGroup.SelectedObject;
            if ~isempty(app.currentRGB)
                doSegmentation(app); 
            end
        end

        % Value changed function: CheckBoxUseGrids
        function CheckBoxUseGridsValueChanged(app, event)
            value = app.CheckBoxUseGrids.Value;
            if app.PatternTypeC.Value
                app.CheckBoxUseGrids.Enable = "on";
                if app.CheckBoxUseGrids.Value % if use grids, disable individual area and std dev calculation
                    app.PerleafareaCheckBox.Enable = "off";
                    app.AreaStdDevCheckBox.Enable = "off";
                else
                    app.PerleafareaCheckBox.Enable = "on";
                    app.AreaStdDevCheckBox.Enable = "on";
                end
            else
                app.CheckBoxUseGrids.Enable = "off";
                app.PerleafareaCheckBox.Enable = "on";
                app.AreaStdDevCheckBox.Enable = "on";
            end            
        end

        % Callback function
        function DarkModeCheckBoxValueChanged(app, event)
            if ~isempty(app.currentRGB)
                doSegmentation(app); 
            end
            
        end

        % Callback function
        function ModeButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ModeButtonGroup.SelectedObject;
            if ~isempty(app.currentRGB)
                doSegmentation(app); 
            end
            
        end

        % Value changed function: PerspectiveCorrectionCheckBox
        function PerspectiveCorrectionCheckBoxValueChanged(app, event)
            if ~isempty(app.currentRGB)
                doSegmentation(app); 
            end             
        end

        % Value changed function: ExGCheckBox
        function ExGCheckBoxValueChanged(app, event)
            if ~isempty(app.currentRGB)
                doSegmentation(app); 
            end            
        end

        % Value changed function: HRFICheckBox
        function HRFICheckBoxValueChanged(app, event)
            if ~isempty(app.currentRGB)
                doSegmentation(app); 
            end
        end

        % Callback function
        function LoGCheckBoxValueChanged(app, event)
            if ~isempty(app.currentRGB)
                doSegmentation(app); 
            end
        end

        % Callback function
        function aCheckBoxValueChanged(app, event)
            doSegmentation(app);            
        end

        % Callback function
        function bCheckBoxValueChanged(app, event)
            doSegmentation(app);
        end

        % Value changed function: TextureCheckBox
        function TextureCheckBoxValueChanged(app, event)
            doSegmentation(app);
        end

        % Value changed function: LoGCheckBox
        function LoGCheckBoxValueChanged2(app, event)
            doSegmentation(app);
        end

        % Value changed function: AttemptsEditField
        function AttemptsEditFieldValueChanged(app, event)
            doSegmentation(app);            
        end

        % Value changed function: CCheckBox
        function CCheckBoxValueChanged(app, event)
            doSegmentation(app);            
        end

        % Value changed function: TextureMCheckBox
        function TextureMCheckBoxValueChanged(app, event)
            doSegmentation(app);            
        end

        % Value changed function: TextureDCheckBox
        function TextureDCheckBoxValueChanged(app, event)
            doSegmentation(app);             
        end

        % Value changed function: FillholesCheckBox
        function FillholesCheckBoxValueChanged(app, event)
            doSegmentation(app);            
        end

        % Callback function
        function LCheckBoxValueChanged(app, event)
            doSegmentation(app);            
        end

        % Callback function
        function HCheckBoxValueChanged(app, event)
            doSegmentation(app);            
        end

        % Callback function
        function SCheckBoxValueChanged(app, event)
            doSegmentation(app);            
        end

        % Callback function
        function VCheckBoxValueChanged(app, event)
            doSegmentation(app);            
        end

        % Value changed function: WatershedCheckBox
        function WatershedCheckBoxValueChanged(app, event)
            value = app.WatershedCheckBox.Value;
            if value
                app.WatershedMinimaSpinner.Enable = "on";
            else
                app.WatershedMinimaSpinner.Enable = "off";
            end
            doSegmentation(app);  
        end

        % Value changed function: WatershedMinimaSpinner
        function WatershedMinimaSpinnerValueChanged(app, event)
            doSegmentation(app);            
        end

        % Value changed function: DGCICheckBox
        function DGCICheckBoxValueChanged(app, event)
            doSegmentation(app);             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LeafAnalyzerUIFigure = uifigure('Visible', 'off');
            app.LeafAnalyzerUIFigure.Name = 'Leaf Analyzer';
            app.LeafAnalyzerUIFigure.SizeChangedFcn = createCallbackFcn(app, @LeafAreaTabSizeChanged, true);
            app.TabGroup = uitabgroup(app.LeafAnalyzerUIFigure);
            app.LeafAreaTab = uitab(app.TabGroup);
            app.LeafAreaTab.SizeChangedFcn = createCallbackFcn(app, @LeafAreaTabSizeChanged, true);
            app.LeafAreaTab.Title = 'Leaf Area';
            app.Step1SelectmethodpatternimagepathPanel = uipanel(app.LeafAreaTab);
            app.Step1SelectmethodpatternimagepathPanel.Title = ' Step 1: Select method, pattern, image path';
            app.SelectFolderButton = uibutton(app.Step1SelectmethodpatternimagepathPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.PathText = uilabel(app.Step1SelectmethodpatternimagepathPanel);
            app.PathText.Tag = 'pathtext';
            app.PathText.Text = '';
            app.NumberofimagesLabel = uilabel(app.Step1SelectmethodpatternimagepathPanel);
            app.NumberofimagesLabel.Text = 'Number of images:';
            app.NumberOfImagesText = uilabel(app.Step1SelectmethodpatternimagepathPanel);
            app.NumberOfImagesText.Text = '0';
            app.SegMethodButtonGroup = uibuttongroup(app.Step1SelectmethodpatternimagepathPanel);
            app.SegMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegMethodButtonGroupSelectionChanged, true);
            app.ClusteringButton = uiradiobutton(app.SegMethodButtonGroup);
            app.ClusteringButton.Text = 'Clustering';
            app.ClusteringButton.Value = true;
            app.ThresholdingButton = uiradiobutton(app.SegMethodButtonGroup);
            app.ThresholdingButton.Text = 'Thresholding';
            app.MethodLabel = uilabel(app.SegMethodButtonGroup);
            app.MethodLabel.Text = 'Method:';
            app.SelectImageButton_2 = uibutton(app.Step1SelectmethodpatternimagepathPanel, 'push');
            app.SelectImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_2Pushed, true);
            app.SelectImageButton_2.Text = 'Select Image';
            app.PatternTypeButtonGroup = uibuttongroup(app.Step1SelectmethodpatternimagepathPanel);
            app.PatternTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PatternTypeButtonGroupSelectionChanged, true);
            app.PatternTypeA = uiradiobutton(app.PatternTypeButtonGroup);
            app.PatternTypeA.Text = 'Type A';
            app.PatternTypeA.Value = true;
            app.PatternTypeB = uiradiobutton(app.PatternTypeButtonGroup);
            app.PatternTypeB.Text = 'Type B';
            app.PatternLabel = uilabel(app.PatternTypeButtonGroup);
            app.PatternLabel.Text = 'Pattern:';
            app.PatternTypeC = uiradiobutton(app.PatternTypeButtonGroup);
            app.PatternTypeC.Text = 'Type C';
            app.Step3RunPanel = uipanel(app.LeafAreaTab);
            app.Step3RunPanel.Title = ' Step 3: Run';
            app.Step3RunPanel.SizeChangedFcn = createCallbackFcn(app, @Step3RunPanelSizeChanged, true);
            app.RunButton = uibutton(app.Step3RunPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ProgressBar = wt.ProgressBar(app.Step3RunPanel);
            app.CurrentImageFullName = uilabel(app.Step3RunPanel);
            app.CurrentImageFullName.Text = '';
            app.LeafAreaPreviewPanel = uipanel(app.LeafAreaTab);
            app.LeafAreaPreviewPanel.Title = 'Preview';
            app.AxesResultPanel = uipanel(app.LeafAreaPreviewPanel);
            app.UIAxesResult = uiaxes(app.AxesResultPanel);
            app.UIAxesResult.XTick = [];
            app.UIAxesResult.YTick = [];
            app.AxesSourcePanel = uipanel(app.LeafAreaPreviewPanel);
            app.UIAxesSource = uiaxes(app.AxesSourcePanel);
            app.UIAxesSource.XTick = [];
            app.UIAxesSource.YTick = [];
            app.Step2SettingsPanel = uipanel(app.LeafAreaTab);
            app.Step2SettingsPanel.Title = ' Step 2: Settings';
            app.SettingsTabGroup = uitabgroup(app.Step2SettingsPanel);
            app.OutputTab = uitab(app.SettingsTabGroup);
            app.OutputTab.Title = 'Output';
            app.OutputpathEditFieldLabel = uilabel(app.OutputTab);
            app.OutputpathEditFieldLabel.Text = 'Output path:';
            app.OutputpathEditField = uieditfield(app.OutputTab, 'text');
            app.BrowseButton = uibutton(app.OutputTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.OutputFormatDropDownLabel = uilabel(app.OutputTab);
            app.OutputFormatDropDownLabel.Text = 'Output Format:';
            app.OutputFormatDropDown = uidropdown(app.OutputTab);
            app.OutputFormatDropDown.Items = {'.xls', '.csv'};
            app.OutputFormatDropDown.Value = '.xls';
            app.PatterndetectionoverlayCheckBox = uicheckbox(app.OutputTab);
            app.PatterndetectionoverlayCheckBox.Text = 'Pattern detection overlay';
            app.OCRdetectionoverlayCheckBox = uicheckbox(app.OutputTab);
            app.OCRdetectionoverlayCheckBox.Text = 'OCR detection overlay';
            app.BinaryleafmaskCheckBox = uicheckbox(app.OutputTab);
            app.BinaryleafmaskCheckBox.Text = 'Binary leaf mask';
            app.BinaryleafmaskCheckBox.Value = true;
            app.MaskedRGBimageCheckBox = uicheckbox(app.OutputTab);
            app.MaskedRGBimageCheckBox.Text = 'Masked RGB image';
            app.MaskedRGBimageCheckBox.Value = true;
            app.TotalareaCheckBox = uicheckbox(app.OutputTab);
            app.TotalareaCheckBox.Text = 'Total area';
            app.TotalareaCheckBox.Value = true;
            app.MeanareaCheckBox = uicheckbox(app.OutputTab);
            app.MeanareaCheckBox.Text = 'Mean area';
            app.MeanareaCheckBox.Value = true;
            app.LeafcountCheckBox = uicheckbox(app.OutputTab);
            app.LeafcountCheckBox.Text = 'Leaf count';
            app.LeafcountCheckBox.Value = true;
            app.MaxleafareaCheckBox = uicheckbox(app.OutputTab);
            app.MaxleafareaCheckBox.Text = 'Max leaf area';
            app.MaxleafareaCheckBox.Value = true;
            app.MaxleafdimensionsCheckBox = uicheckbox(app.OutputTab);
            app.MaxleafdimensionsCheckBox.Text = 'Max leaf dimensions';
            app.MeasurementunitsLabel = uilabel(app.OutputTab);
            app.MeasurementunitsLabel.Text = 'Units:';
            app.UnitsDropDown = uidropdown(app.OutputTab);
            app.UnitsDropDown.Items = {'mm', 'pixel'};
            app.UnitsDropDown.Value = 'mm';
            app.LabeltextCheckBox = uicheckbox(app.OutputTab);
            app.LabeltextCheckBox.Text = 'Label text';
            app.AreaStdDevCheckBox = uicheckbox(app.OutputTab);
            app.AreaStdDevCheckBox.Text = 'Area Std Dev';
            app.PerleafareaCheckBox = uicheckbox(app.OutputTab);
            app.PerleafareaCheckBox.Text = 'Per-leaf area';
            app.PerleafdimensionsCheckBox = uicheckbox(app.OutputTab);
            app.PerleafdimensionsCheckBox.Text = 'Per-leaf dimensions';
            app.PerleafperimeterCheckBox = uicheckbox(app.OutputTab);
            app.PerleafperimeterCheckBox.Text = 'Per-leaf perimeter';
            app.MeangreenleafindexCheckBox = uicheckbox(app.OutputTab);
            app.MeangreenleafindexCheckBox.Text = 'Mean green leaf index';
            app.PerleafgreenindexCheckBox = uicheckbox(app.OutputTab);
            app.PerleafgreenindexCheckBox.Text = 'Per-leaf green index';
            app.ClusteringTab = uitab(app.SettingsTabGroup);
            app.ClusteringTab.Title = 'Clustering';
            app.ClustersEditFieldLabel = uilabel(app.ClusteringTab);
            app.ClustersEditFieldLabel.Text = 'Clusters:';
            app.ClustersEditField = uieditfield(app.ClusteringTab, 'numeric');
            app.ClustersEditField.Editable = 'off';
            app.ClustersEditField.Value = 2;
            app.AttemptsEditFieldLabel = uilabel(app.ClusteringTab);
            app.AttemptsEditFieldLabel.Text = 'Attempts';
            app.AttemptsEditField = uieditfield(app.ClusteringTab, 'numeric');
            app.AttemptsEditField.ValueChangedFcn = createCallbackFcn(app, @AttemptsEditFieldValueChanged, true);
            app.AttemptsEditField.Value = 2;
            app.PatternTypeButtonGroup_2 = uibuttongroup(app.ClusteringTab);
            app.FeaturesLabel = uilabel(app.PatternTypeButtonGroup_2);
            app.FeaturesLabel.Text = 'Features';
            app.ExGCheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.ExGCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExGCheckBoxValueChanged, true);
            app.ExGCheckBox.Text = 'ExG';
            app.HRFICheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.HRFICheckBox.ValueChangedFcn = createCallbackFcn(app, @HRFICheckBoxValueChanged, true);
            app.HRFICheckBox.Text = 'HRFI';
            app.LoGCheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.LoGCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoGCheckBoxValueChanged2, true);
            app.LoGCheckBox.Text = 'LoG';
            app.TextureCheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.TextureCheckBox.ValueChangedFcn = createCallbackFcn(app, @TextureCheckBoxValueChanged, true);
            app.TextureCheckBox.Text = 'Texture';
            app.CCheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.CCheckBox.ValueChangedFcn = createCallbackFcn(app, @CCheckBoxValueChanged, true);
            app.CCheckBox.Text = 'C';
            app.TextureMCheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.TextureMCheckBox.ValueChangedFcn = createCallbackFcn(app, @TextureMCheckBoxValueChanged, true);
            app.TextureMCheckBox.Text = 'TextureM';
            app.TextureDCheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.TextureDCheckBox.ValueChangedFcn = createCallbackFcn(app, @TextureDCheckBoxValueChanged, true);
            app.TextureDCheckBox.Text = 'TextureD';
            app.LBSCheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.LBSCheckBox.Enable = 'off';
            app.LBSCheckBox.Text = 'LBS';
            app.LBSCheckBox.Value = true;
            app.DGCICheckBox = uicheckbox(app.PatternTypeButtonGroup_2);
            app.DGCICheckBox.ValueChangedFcn = createCallbackFcn(app, @DGCICheckBoxValueChanged, true);
            app.DGCICheckBox.Text = 'DGCI';
            app.ThresholdingTab = uitab(app.SettingsTabGroup);
            app.ThresholdingTab.Title = 'Thresholding';
            app.HMinValue = uieditfield(app.ThresholdingTab, 'numeric');
            app.HMinValue.Limits = [0 1];
            app.HMinValue.ValueDisplayFormat = '%.3f';
            app.HMinValue.ValueChangedFcn = createCallbackFcn(app, @HMinValueValueChanged, true);
            app.HMaxValue = uieditfield(app.ThresholdingTab, 'numeric');
            app.HMaxValue.Limits = [0 1];
            app.HMaxValue.ValueDisplayFormat = '%.3f';
            app.HMaxValue.ValueChangedFcn = createCallbackFcn(app, @HMaxValueValueChanged, true);
            app.HMaxValue.Value = 0.598;
            app.HMinSliderLabel = uilabel(app.ThresholdingTab);
            app.HMinSliderLabel.Text = 'H-Min:';
            app.HMinSlider = uislider(app.ThresholdingTab);
            app.HMinSlider.Limits = [0 1];
            app.HMinSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.HMinSlider.ValueChangedFcn = createCallbackFcn(app, @HMinSliderValueChanged, true);
            app.HMinSlider.ValueChangingFcn = createCallbackFcn(app, @HMinSliderValueChanging, true);
            app.HMaxSliderLabel = uilabel(app.ThresholdingTab);
            app.HMaxSliderLabel.Text = 'H-Max:';
            app.HMaxSlider = uislider(app.ThresholdingTab);
            app.HMaxSlider.Limits = [0 1];
            app.HMaxSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.HMaxSlider.ValueChangedFcn = createCallbackFcn(app, @HMaxSliderValueChanged, true);
            app.HMaxSlider.ValueChangingFcn = createCallbackFcn(app, @HMaxSliderValueChanging, true);
            app.HMaxSlider.Value = 0.598;
            app.SMaxValue = uieditfield(app.ThresholdingTab, 'numeric');
            app.SMaxValue.Limits = [0 1];
            app.SMaxValue.ValueDisplayFormat = '%.3f';
            app.SMaxValue.ValueChangedFcn = createCallbackFcn(app, @SMaxValueValueChanged, true);
            app.SMaxValue.Value = 1;
            app.SMinSliderLabel = uilabel(app.ThresholdingTab);
            app.SMinSliderLabel.Text = 'S-Min:';
            app.SMinSlider = uislider(app.ThresholdingTab);
            app.SMinSlider.Limits = [0 1];
            app.SMinSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.SMinSlider.ValueChangedFcn = createCallbackFcn(app, @SMinSliderValueChanged, true);
            app.SMinSlider.ValueChangingFcn = createCallbackFcn(app, @SMinSliderValueChanging, true);
            app.SMinSlider.Value = 0.38;
            app.SMaxSliderLabel = uilabel(app.ThresholdingTab);
            app.SMaxSliderLabel.Text = 'S-Max:';
            app.SMaxSlider = uislider(app.ThresholdingTab);
            app.SMaxSlider.Limits = [0 1];
            app.SMaxSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.SMaxSlider.ValueChangedFcn = createCallbackFcn(app, @SMaxSliderValueChanged, true);
            app.SMaxSlider.ValueChangingFcn = createCallbackFcn(app, @SMaxSliderValueChanging, true);
            app.SMaxSlider.Value = 1;
            app.VMinValue = uieditfield(app.ThresholdingTab, 'numeric');
            app.VMinValue.Limits = [0 1];
            app.VMinValue.ValueDisplayFormat = '%.3f';
            app.VMinValue.ValueChangedFcn = createCallbackFcn(app, @VMinValueValueChanged, true);
            app.VMinValue.Value = 0.228;
            app.VMaxValue = uieditfield(app.ThresholdingTab, 'numeric');
            app.VMaxValue.Limits = [0 1];
            app.VMaxValue.ValueDisplayFormat = '%.3f';
            app.VMaxValue.ValueChangedFcn = createCallbackFcn(app, @VMaxValueValueChanged, true);
            app.VMaxValue.Value = 0.784;
            app.VMinSliderLabel = uilabel(app.ThresholdingTab);
            app.VMinSliderLabel.Text = 'V-Min:';
            app.VMinSlider = uislider(app.ThresholdingTab);
            app.VMinSlider.Limits = [0 1];
            app.VMinSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.VMinSlider.ValueChangedFcn = createCallbackFcn(app, @VMinSliderValueChanged, true);
            app.VMinSlider.ValueChangingFcn = createCallbackFcn(app, @VMinSliderValueChanging, true);
            app.VMinSlider.Value = 0.228;
            app.VMaxSliderLabel = uilabel(app.ThresholdingTab);
            app.VMaxSliderLabel.Text = 'V-Max:';
            app.VMaxSlider = uislider(app.ThresholdingTab);
            app.VMaxSlider.Limits = [0 1];
            app.VMaxSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.VMaxSlider.ValueChangedFcn = createCallbackFcn(app, @VMaxSliderValueChanged, true);
            app.VMaxSlider.ValueChangingFcn = createCallbackFcn(app, @VMaxSliderValueChanging, true);
            app.VMaxSlider.Value = 0.784;
            app.SelectRegionButton = uibutton(app.ThresholdingTab, 'push');
            app.SelectRegionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRegionButtonPushed, true);
            app.SelectRegionButton.Text = 'Select Region';
            app.ResetSettingsButton = uibutton(app.ThresholdingTab, 'push');
            app.ResetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSettingsButtonPushed, true);
            app.ResetSettingsButton.Visible = 'off';
            app.ResetSettingsButton.Text = 'Reset Settings';
            app.SMinValue = uieditfield(app.ThresholdingTab, 'numeric');
            app.SMinValue.Limits = [0 1];
            app.SMinValue.ValueDisplayFormat = '%.3f';
            app.SMinValue.ValueChangedFcn = createCallbackFcn(app, @SMinValueValueChanged, true);
            app.SMinValue.Value = 0.38;
            app.SaveSettingsButton = uibutton(app.ThresholdingTab, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.ThresholdingTab, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.PatternTab = uitab(app.SettingsTabGroup);
            app.PatternTab.Title = 'Pattern';
            app.PatternWidthEditFieldLabel = uilabel(app.PatternTab);
            app.PatternWidthEditFieldLabel.Text = 'Pattern Width';
            app.PatternWidthEditField = uieditfield(app.PatternTab, 'numeric');
            app.PatternWidthEditField.Limits = [0 Inf];
            app.PatternWidthEditField.ValueChangedFcn = createCallbackFcn(app, @PatternWidthEditFieldValueChanged, true);
            app.PatternHeightEditFieldLabel = uilabel(app.PatternTab);
            app.PatternHeightEditFieldLabel.Text = 'Pattern Height';
            app.PatternHeightEditField = uieditfield(app.PatternTab, 'numeric');
            app.PatternHeightEditField.Limits = [0 Inf];
            app.PatternHeightEditField.ValueChangedFcn = createCallbackFcn(app, @PatternHeightEditFieldValueChanged, true);
            app.CheckBoxUseGrids = uicheckbox(app.PatternTab);
            app.CheckBoxUseGrids.ValueChangedFcn = createCallbackFcn(app, @CheckBoxUseGridsValueChanged, true);
            app.CheckBoxUseGrids.Text = '';
            app.UsegridsLabel = uilabel(app.PatternTab);
            app.UsegridsLabel.Text = 'Use grids';
            app.mmLabel = uilabel(app.PatternTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.PatternTab);
            app.mmLabel_2.Text = 'mm';
            app.ProgressBar2 = wt.ProgressBar(app.PatternTab);
            app.BorderShrinkEditFieldLabel = uilabel(app.PatternTab);
            app.BorderShrinkEditFieldLabel.Text = 'Border Shrink';
            app.BorderShrinkEditField = uieditfield(app.PatternTab, 'numeric');
            app.BorderShrinkEditField.Value = 3;
            app.PixelsLabel = uilabel(app.PatternTab);
            app.PixelsLabel.Text = 'Pixels';
            app.mmLabel_3 = uilabel(app.PatternTab);
            app.mmLabel_3.Text = 'mm';
            app.AprilTagLengthEditFieldLabel = uilabel(app.PatternTab);
            app.AprilTagLengthEditFieldLabel.Text = 'AprilTag Length';
            app.AprilTagLengthEditField = uieditfield(app.PatternTab, 'numeric');
            app.AprilTagLengthEditField.Limits = [0 Inf];
            app.AdvancedTab = uitab(app.SettingsTabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.ofMaxLeafAreaLabel = uilabel(app.AdvancedTab);
            app.ofMaxLeafAreaLabel.Text = '%  of Max Leaf Area';
            app.MinLeafAreaEditFieldLabel = uilabel(app.AdvancedTab);
            app.MinLeafAreaEditFieldLabel.Text = 'Min Leaf Area:';
            app.MinLeafAreaEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MinLeafAreaEditField.Limits = [0 100];
            app.MinLeafAreaEditField.ValueChangedFcn = createCallbackFcn(app, @MinLeafAreaEditFieldValueChanged, true);
            app.MinLeafAreaEditField.Value = 5;
            app.PerspectiveCorrectionCheckBox = uicheckbox(app.AdvancedTab);
            app.PerspectiveCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PerspectiveCorrectionCheckBoxValueChanged, true);
            app.PerspectiveCorrectionCheckBox.Text = ' Perspective Correction';
            app.ofOrignalImageLabel = uilabel(app.AdvancedTab);
            app.ofOrignalImageLabel.Text = '%  of Orignal Image';
            app.ResizeImageEditFieldLabel = uilabel(app.AdvancedTab);
            app.ResizeImageEditFieldLabel.Text = 'Resize Image:';
            app.ResizeImageEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.ResizeImageEditField.Limits = [0 400];
            app.ResizeImageEditField.Value = 100;
            app.FillholesCheckBox = uicheckbox(app.AdvancedTab);
            app.FillholesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillholesCheckBoxValueChanged, true);
            app.FillholesCheckBox.Text = 'Fill holes';
            app.FillholesCheckBox.Value = true;
            app.WatershedCheckBox = uicheckbox(app.AdvancedTab);
            app.WatershedCheckBox.ValueChangedFcn = createCallbackFcn(app, @WatershedCheckBoxValueChanged, true);
            app.WatershedCheckBox.Text = 'Watershed';
            app.MinimaThresholdSpinnerLabel = uilabel(app.AdvancedTab);
            app.MinimaThresholdSpinnerLabel.Enable = 'off';
            app.MinimaThresholdSpinnerLabel.Text = 'Minima Threshold';
            app.WatershedMinimaSpinner = uispinner(app.AdvancedTab);
            app.WatershedMinimaSpinner.Step = 0.1;
            app.WatershedMinimaSpinner.Limits = [0 Inf];
            app.WatershedMinimaSpinner.ValueChangedFcn = createCallbackFcn(app, @WatershedMinimaSpinnerValueChanged, true);
            app.WatershedMinimaSpinner.Enable = 'off';
            app.WatershedMinimaSpinner.Value = 0.8;
            app.LeafDamageTab = uitab(app.TabGroup);
            app.LeafDamageTab.Title = 'Leaf Damage';
            app.LeafDamageOptionsPanel = uipanel(app.LeafDamageTab);
            app.LeafDamageOptionsPanel.Title = 'Options';
            app.ButtonGroup_3 = uibuttongroup(app.LeafDamageOptionsPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.TypeHealthyButton = uiradiobutton(app.ButtonGroup_3);
            app.TypeHealthyButton.Text = 'Healthy';
            app.TypeDiseasedButton = uiradiobutton(app.ButtonGroup_3);
            app.TypeDiseasedButton.Text = 'Diseased';
            app.TypeDiseasedButton.Value = true;
            app.TypeLabel = uilabel(app.ButtonGroup_3);
            app.TypeLabel.Text = 'Type:';
            app.TypeGrazedButton = uiradiobutton(app.ButtonGroup_3);
            app.TypeGrazedButton.Text = 'Grazed';
            app.SelectImageButton = uibutton(app.LeafDamageOptionsPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.DamagedImageFullPath = uilabel(app.LeafDamageOptionsPanel);
            app.DamagedImageFullPath.Text = '';
            app.DrawMarginButton = uibutton(app.LeafDamageOptionsPanel, 'push');
            app.DrawMarginButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMarginButtonPushed, true);
            app.DrawMarginButton.Text = 'Draw Margin';
            app.DrawROIButton = uibutton(app.LeafDamageOptionsPanel, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI';
            app.LeafDamagePreviewPanel = uipanel(app.LeafDamageTab);
            app.LeafDamagePreviewPanel.Title = 'Preview';
            app.UIAxesDamagedLeaf = uiaxes(app.LeafDamagePreviewPanel);
            app.UIAxesDamagedLeaf.XTick = [];
            app.UIAxesDamagedLeaf.YTick = [];
            app.UIAxesCluster1 = uiaxes(app.LeafDamagePreviewPanel);
            app.UIAxesCluster1.XTick = [];
            app.UIAxesCluster1.YTick = [];
            app.UIAxesCluster2 = uiaxes(app.LeafDamagePreviewPanel);
            app.UIAxesCluster2.XTick = [];
            app.UIAxesCluster2.YTick = [];
            app.UIAxesCluster3 = uiaxes(app.LeafDamagePreviewPanel);
            app.UIAxesCluster3.XTick = [];
            app.UIAxesCluster3.YTick = [];
            app.HyperspecTab = uitab(app.TabGroup);
            app.HyperspecTab.Title = 'Hyperspec';
            app.OptionsPanel_2 = uipanel(app.HyperspecTab);
            app.OptionsPanel_2.Title = 'Options';
            app.SelectImageButton_HSI = uibutton(app.OptionsPanel_2, 'push');
            app.SelectImageButton_HSI.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_HSIPushed, true);
            app.SelectImageButton_HSI.Text = 'Select Image';
            app.IndicesButtonGroup = uibuttongroup(app.OptionsPanel_2);
            app.IndicesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IndicesButtonGroupSelectionChanged, true);
            app.RGBIndexButton = uiradiobutton(app.IndicesButtonGroup);
            app.RGBIndexButton.Text = 'RGB';
            app.RGBIndexButton.Value = true;
            app.NDVIIndexButton = uiradiobutton(app.IndicesButtonGroup);
            app.NDVIIndexButton.Text = 'NDVI';
            app.ShowLabel = uilabel(app.IndicesButtonGroup);
            app.ShowLabel.Text = 'Show';
            app.EVIIndexButton = uiradiobutton(app.IndicesButtonGroup);
            app.EVIIndexButton.Text = 'EVI';
            app.MCARIIndexButton = uiradiobutton(app.IndicesButtonGroup);
            app.MCARIIndexButton.Text = 'MCARI';
            app.DetectPatternCheckBox = uicheckbox(app.OptionsPanel_2);
            app.DetectPatternCheckBox.Text = ' Detect Pattern';
            app.PreviewPanel_3 = uipanel(app.HyperspecTab);
            app.PreviewPanel_3.Title = 'Preview';
            app.UIAxesHyperResult = uiaxes(app.PreviewPanel_3);
            app.UIAxesHyperResult.XTick = [];
            app.UIAxesHyperResult.YTick = [];
            app.UIAxesHyperResult.ButtonDownFcn = createCallbackFcn(app, @UIAxesHyperResultButtonDown, true);
            % Show the figure after all components are created
            app.LeafAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Leaf_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LeafAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LeafAnalyzerUIFigure)
        end
    end
end